classdef DYNOT4D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        VisualizacionPreProcesadoButton  matlab.ui.control.Button
        volumenesIndividualesCheckBox  matlab.ui.control.CheckBox
        PreprocesadoButton             matlab.ui.control.Button
        RemuestreoDeDatosCheckBox      matlab.ui.control.CheckBox
        dimensionDeVolumenLabel        matlab.ui.control.Label
        dimensionesVolumenListBox      matlab.ui.control.ListBox
        CalculoImagenesButton          matlab.ui.control.Button
        OpcionalFiltroLabel            matlab.ui.control.Label
        ConfiguracionAvanzadaTextArea  matlab.ui.control.TextArea
        CargaFicherosFEMButton         matlab.ui.control.Button
        UsaOptodoReferenciaCheckBox    matlab.ui.control.CheckBox
        CalculoValoresHemoglobinaButton  matlab.ui.control.Button
        OpcionalFiltroDeDatosListBox   matlab.ui.control.ListBox
        CargaDatosNIRXDYNOTButton      matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        etiquetasEventosEditField      matlab.ui.control.EditField
        EtqEvLabel                     matlab.ui.control.Label
        escalaColorButtonGroup         matlab.ui.container.ButtonGroup
        escalaColorAbsLogButton        matlab.ui.control.RadioButton
        scalaColorLogButton            matlab.ui.control.RadioButton
        escalaColorSqrtButton          matlab.ui.control.RadioButton
        escalaColorLinealButton        matlab.ui.control.RadioButton
        RestarMediaTemporalVolumenesCheckBox  matlab.ui.control.CheckBox
        TiempoEdit                     matlab.ui.control.NumericEditField
        TiempoSlider                   matlab.ui.control.Slider
        TiempoSliderLabel              matlab.ui.control.Label
        vistaVolumenLabel              matlab.ui.control.Label
        vistaVolumenButtonGroup        matlab.ui.container.ButtonGroup
        vistaVolumenCoronalButton      matlab.ui.control.RadioButton
        vistaVolumenSagitalButton      matlab.ui.control.RadioButton
        vistaVolumenAxialButton        matlab.ui.control.RadioButton
        ComponenteImagenLabel          matlab.ui.control.Label
        ComponenteImagenButtonGroup    matlab.ui.container.ButtonGroup
        FaseButton                     matlab.ui.control.RadioButton
        ImagButton                     matlab.ui.control.RadioButton
        RealButton                     matlab.ui.control.RadioButton
        MagnitudButton                 matlab.ui.control.RadioButton
        enProcesamientoButton          matlab.ui.control.Button
        seriesImagenesSwitch           matlab.ui.control.Switch
        seriesImagenesLabel            matlab.ui.control.Label
        volumenDeImagenEditField       matlab.ui.control.NumericEditField
        volumenDeImagenSlider          matlab.ui.control.Slider
        volumendeImagenLabel           matlab.ui.control.Label
        eventosEditField               matlab.ui.control.EditField
        EventosEditFieldLabel          matlab.ui.control.Label
        limpiarRegistroEventosButton   matlab.ui.control.Button
        corteInferiorEditField         matlab.ui.control.NumericEditField
        CorteinferiorHzEditFieldLabel  matlab.ui.control.Label
        TipoDeSerie                    matlab.ui.container.ButtonGroup
        OD830RadioButton               matlab.ui.control.RadioButton
        OD760RadioButton               matlab.ui.control.RadioButton
        HbSatLogRadioButton            matlab.ui.control.RadioButton
        HbSatRadioButton               matlab.ui.control.RadioButton
        HbTotRadioButton               matlab.ui.control.RadioButton
        HbRRadioButton                 matlab.ui.control.RadioButton
        HbORadioButton                 matlab.ui.control.RadioButton
        longitudOnda830RadioButton     matlab.ui.control.RadioButton
        longitudOnda760RadioButton     matlab.ui.control.RadioButton
        PasoBajoSlider                 matlab.ui.control.Slider
        CortesuperiorHzEditFieldLabel  matlab.ui.control.Label
        CorteSuperiorEditField         matlab.ui.control.NumericEditField
        VerFuentesDetectoresTree       matlab.ui.container.CheckBoxTree
        VerFuentesCheckbox             matlab.ui.container.TreeNode
        VerFuente01Checkbox            matlab.ui.container.TreeNode
        VerFuente02Checkbox            matlab.ui.container.TreeNode
        VerFuente03Checkbox            matlab.ui.container.TreeNode
        VerFuente04Checkbox            matlab.ui.container.TreeNode
        VerFuente05Checkbox            matlab.ui.container.TreeNode
        VerFuente06Checkbox            matlab.ui.container.TreeNode
        VerFuente07Checkbox            matlab.ui.container.TreeNode
        VerFuente08Checkbox            matlab.ui.container.TreeNode
        VerFuente09Checkbox            matlab.ui.container.TreeNode
        VerFuente10Checkbox            matlab.ui.container.TreeNode
        VerFuente11Checkbox            matlab.ui.container.TreeNode
        VerFuente12Checkbox            matlab.ui.container.TreeNode
        VerFuente13Checkbox            matlab.ui.container.TreeNode
        VerFuente14Checkbox            matlab.ui.container.TreeNode
        VerFuente15Checkbox            matlab.ui.container.TreeNode
        VerFuente16Checkbox            matlab.ui.container.TreeNode
        VerFuente17Checkbox            matlab.ui.container.TreeNode
        VerFuente18Checkbox            matlab.ui.container.TreeNode
        VerFuente19Checkbox            matlab.ui.container.TreeNode
        VerFuente20Checkbox            matlab.ui.container.TreeNode
        VerFuente21Checkbox            matlab.ui.container.TreeNode
        VerFuente22Checkbox            matlab.ui.container.TreeNode
        VerFuente23Checkbox            matlab.ui.container.TreeNode
        VerFuente24Checkbox            matlab.ui.container.TreeNode
        VerFuente25Checkbox            matlab.ui.container.TreeNode
        VerFuente26Checkbox            matlab.ui.container.TreeNode
        VerFuente27Checkbox            matlab.ui.container.TreeNode
        VerFuente28Checkbox            matlab.ui.container.TreeNode
        VerFuente29Checkbox            matlab.ui.container.TreeNode
        VerFuente30Checkbox            matlab.ui.container.TreeNode
        VerFuente31Checkbox            matlab.ui.container.TreeNode
        VerFuente32Checkbox            matlab.ui.container.TreeNode
        VerDetectoresCheckbox          matlab.ui.container.TreeNode
        VerDetector01Checkbox          matlab.ui.container.TreeNode
        VerDetector02Checkbox          matlab.ui.container.TreeNode
        VerDetector03Checkbox          matlab.ui.container.TreeNode
        VerDetector04Checkbox          matlab.ui.container.TreeNode
        VerDetector05Checkbox          matlab.ui.container.TreeNode
        VerDetector06Checkbox          matlab.ui.container.TreeNode
        VerDetector07Checkbox          matlab.ui.container.TreeNode
        VerDetector08Checkbox          matlab.ui.container.TreeNode
        VerDetector09Checkbox          matlab.ui.container.TreeNode
        VerDetector10Checkbox          matlab.ui.container.TreeNode
        VerDetector11Checkbox          matlab.ui.container.TreeNode
        VerDetector12Checkbox          matlab.ui.container.TreeNode
        VerDetector13Checkbox          matlab.ui.container.TreeNode
        VerDetector14Checkbox          matlab.ui.container.TreeNode
        VerDetector15Checkbox          matlab.ui.container.TreeNode
        VerDetector16Checkbox          matlab.ui.container.TreeNode
        VerDetector17Checkbox          matlab.ui.container.TreeNode
        VerDetector18Checkbox          matlab.ui.container.TreeNode
        VerDetector19Checkbox          matlab.ui.container.TreeNode
        VerDetector20Checkbox          matlab.ui.container.TreeNode
        VerDetector21Checkbox          matlab.ui.container.TreeNode
        VerDetector22Checkbox          matlab.ui.container.TreeNode
        VerDetector23Checkbox          matlab.ui.container.TreeNode
        VerDetector24Checkbox          matlab.ui.container.TreeNode
        VerDetector25Checkbox          matlab.ui.container.TreeNode
        VerDetector26Checkbox          matlab.ui.container.TreeNode
        VerDetector27Checkbox          matlab.ui.container.TreeNode
        VerDetector28Checkbox          matlab.ui.container.TreeNode
        VerDetector29Checkbox          matlab.ui.container.TreeNode
        VerDetector30Checkbox          matlab.ui.container.TreeNode
        VerDetector31Checkbox          matlab.ui.container.TreeNode
        VerDetector32Checkbox          matlab.ui.container.TreeNode
        VerDetector33Checkbox          matlab.ui.container.TreeNode
        VerDetector34Checkbox          matlab.ui.container.TreeNode
        VerDetector35Checkbox          matlab.ui.container.TreeNode
        VerDetector36Checkbox          matlab.ui.container.TreeNode
        VerDetector37Checkbox          matlab.ui.container.TreeNode
        VerDetector38Checkbox          matlab.ui.container.TreeNode
        VerDetector39Checkbox          matlab.ui.container.TreeNode
        VerDetector40Checkbox          matlab.ui.container.TreeNode
        VerDetector41Checkbox          matlab.ui.container.TreeNode
        VerDetector42Checkbox          matlab.ui.container.TreeNode
        VerDetector43Checkbox          matlab.ui.container.TreeNode
        VerDetector44Checkbox          matlab.ui.container.TreeNode
        VerDetector45Checkbox          matlab.ui.container.TreeNode
        VerDetector46Checkbox          matlab.ui.container.TreeNode
        VerDetector47Checkbox          matlab.ui.container.TreeNode
        VerDetector48Checkbox          matlab.ui.container.TreeNode
        VerDetector49Checkbox          matlab.ui.container.TreeNode
        VerDetector50Checkbox          matlab.ui.container.TreeNode
        VerDetector51Checkbox          matlab.ui.container.TreeNode
        VerDetector52Checkbox          matlab.ui.container.TreeNode
        VerDetector53Checkbox          matlab.ui.container.TreeNode
        VerDetector54Checkbox          matlab.ui.container.TreeNode
        VerDetector55Checkbox          matlab.ui.container.TreeNode
        VerDetector56Checkbox          matlab.ui.container.TreeNode
        VerDetector57Checkbox          matlab.ui.container.TreeNode
        VerDetector58Checkbox          matlab.ui.container.TreeNode
        VerDetector59Checkbox          matlab.ui.container.TreeNode
        VerDetector60Checkbox          matlab.ui.container.TreeNode
        VerDetector61Checkbox          matlab.ui.container.TreeNode
        VerDetector62Checkbox          matlab.ui.container.TreeNode
        VerDetector63Checkbox          matlab.ui.container.TreeNode
        VerDetector64Checkbox          matlab.ui.container.TreeNode
        PasoAltoSlider                 matlab.ui.control.Slider
        InformacionTextArea            matlab.ui.control.TextArea
        LogoDYNOT4DEditField           matlab.ui.control.EditField
        VerFiguraSeparadaButton        matlab.ui.control.Button
        ImagenesUIAxes                 matlab.ui.control.UIAxes
        SeriesUIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        G_dataInfo = struct();
        botonActualEnProcesamientoStruct = struct();
        canalesMostrados;
        canalesOcultos;
        coordenadasGrid
        coordenadasNodos;
        componenteImagenElegida = 'Magnitud';
        ComponenteImagenFaseEtiqueta = 'Fase';
        ComponenteImagenImaginariaEtiqueta = 'Imaginaria'; 
        ComponenteImagenMagnitudEtiqueta = 'Magnitud';
        ComponenteImagenRealEtiqueta = 'Real';
        condicionElegida = [];
        datosDeConfiguracion = struct(); % datos asociados a fichero de configuración
        datosDeSerie = {};
        datosDeSerieFiltrados = {};
        dimensionesVolumenImagen = [];
        drifterEtiqueta = [];
        eleccionTipoSerie=1;
        epsilonParaFiltro = 1e-4;
        epsilonParaSeries = 1e-6;
        escalaColorAbsLogEtiqueta = 'EscalaColorAbsLog'
        escalaColorElegida = 'EscalaColorLineal';
        escalaColorLinealEtiqueta = 'EscalaColorLineal';
        escalaColorLogEtiqueta = 'EscalaColorLog';
        escalaColorSqrtEtiqueta = 'EscalaColorSqrt';
        eventosMuestras;
        eventosInstantes;
        eventosMarcadores;        
        factoresDeteccionEscalados;
        factoresDeteccionPrecalculados;
        figuraExterna = false;
        frecuenciaMuestreo = [];
        fuentesDetectoresHanCambiado = false;
        indiceSerieWL1 = 1;
        indiceSerieWL2 = 2;
        indiceSerieHbO = 3;
        indiceSerieHbR = 4;
        indiceSerieHbTot = 5;
        indiceSerieHbSat = 6;
        indiceSerieHbSatLog = 7;
        indiceSerieODWL1 = 8;
        indiceSerieODWL2 = 9;
        indicesValidosCoberturaSimple;
        matrizJacobiana;
        matrizJacobianaBlanqueada;
        maximoFuentesAplicacion = 32;
        maximoDetectoresAplicacion = 64;
        nombreCompletoFicheroConfig = []; % nombre completo de fichero de configuración
        nombreCompletoFicheroFactoresDeteccionPrecalculados = []; % fichero det.FEM precalculado
        nombreCompletoFicheroFEM = []; % nombre completo de fichero FEM
        nombreCompletoFicheroJacobiana;
        nombreCompletoFicheroSingulares;
        nombreFicheroConfig = []; % nombre de fichero de configuración (salida de DYNOT)
        nombreFicheroFEM = []; % nombre de fichero (COORDS) de modelo FEM y optodos
        volumenACalcular = [];
        numeroVolumenesACalcular = [];
        numeroCanales = [];
        numeroDetectores = [];
        numeroFuentes = [];
        OrientacionAnteriorEtiqueta = 'Anterior';
        OrientacionDerechaEtiqueta = 'Derecha';
        OrientacionInferiorEtiqueta = 'Inferior';
        OrientacionIzquierdaEtiqueta = 'Izquierda';
        OrientacionPosteriorEtiqueta = 'Posterior';
        pasoAltoFrecuenciaCorte = [];
        pasoAltoEtiqueta = [];
        pasoBandaEtiqueta = [];
        pasoBajoEtiqueta = [];
        pasoBajoFrecuenciaCorte = [];
        pasoBandaFrecuenciasCorte = [];
        procesamiento = struct();            
        remuestrearDatos = false;
        RestarMediaTemporalVolumenes = 0;
        royalBlueColor = [0.25391,0.41016,0.87891];
        rutaDirectorioDetectores = [];
        rutaFicheroConfig = []; % ruta de fichero de configuración
        rutaFicheroFEM = []; % ruta de fichero FEM
        SaturacionLogistica_SCN = [0.5 .999];   % Coeficientes para fórmula de "saturación logística"
        SaturacionLogistica_T_SCN = .96;        % Coeficientes para fórmula de "saturación logística"
        SaturacionLogistica_k2 = .5;            % Coeficientes para fórmula de "saturación logística"
        segundosDeRellenoParaFiltros = 20; % padding inicial y final para evitar efectos de borde
        tamanoEncabezadoFicheroDatosBinarios = 10;
        tiempoElegido = 1;
        tiempoSegundosDatos = [];
        triangulacionDelaunay;
        tsearchnIndicesCoberturaSimple; %% tsearchn
        tsearchnCoordenadasBaricentricas; %% tsearchn
        tsearchnCoordenadasReordenadasCorregidas; %% tsearchn
        tsearchnDiferenciasIndices;
        tsearchnIndicesReordenamiento;
        tsearchnVectorIndiceComienzo;
        tsearchnVectorIndiceFinal;
        UIAxesElegido = 'Series' 
        UIAxesSeriesElegidaEtiqueta = 'Series';
        UIAxesImagenesElegidaEtiqueta = 'Imágenes';
        usoReferencia = 0;
        valoresCalculadosParaNodosFEM = struct('HbO',[],'HbR',[],'HbTot',[],'HBSat',[]);
        valorCorteSVEtiqueta = 'Valor_corte_SV';
        vistaVolumenAxialEtiqueta = 'Axial';
        vistaVolumenCoronalEtiqueta = 'Coronal'; 
        vistaVolumenElegida = 'Axial';
        vistaVolumenSagitalEtiqueta = 'Sagital';
        volumenActualOrientado;
        volumenElegido;
        waveletEtiqueta = [];
    end

    methods (Access = private)
        function actualizarSeries(app,ax,varargin)
            noCalcularCanalesOcultos = false;
            mantenerAxisActual = false;
            if (nargin > 0)
                v = varargin{1};
                if ~isempty(v)
                    noCalcularCanalesOcultos = v{1};
                end
            end
            if (nargin > 1)
                v = varargin{1};
                if ~isempty(v)
                    mantenerAxisActual = v{2};
                end
            end
            if (~noCalcularCanalesOcultos)
                app.calcularCanalesAMostrar();
            end
            ax.XLabel.String = 'sg';
            ax.YLabel.String = 'U.A.';
            datosEntrada = app.datosDeSerie{app.eleccionTipoSerie};
            % datosEntrada = datosEntrada(:,app.canalesMostrados);  %%% Se
            % filtra en calcularDatosFiltrados
            %%% Completamos con ceros los canales de datosDeSerieFiltrados que no
            %%% aparecen en app.canalesAMostrar para calculos posteriores.
            %%% No se muestran los canales ocultos filtrados anulados (==0)
            vectorTiempo=app.tiempoSegundosDatos;
            if (app.remuestrearDatos) %%% remuestreo a 1 Hz
                [datosEntrada,vectorTiempo] = resample(datosEntrada,vectorTiempo,1);
            end

            datosFiltrados = app.calcularDatosFiltrados(datosEntrada);
            if(~isempty(datosFiltrados(:,app.canalesMostrados)))
                app.datosDeSerieFiltrados{app.eleccionTipoSerie} = datosFiltrados;
                if (mantenerAxisActual)
                    hold(ax,'off');
                    XLim = ax.XLim;
                    YLim = [min(arrayfun(@(x) min(x.YData),ax.Children)) max(arrayfun(@(x) max(x.YData),ax.Children))];
                end
                plot(ax,vectorTiempo,datosFiltrados(:,app.canalesMostrados));
                if (mantenerAxisActual)
                    ax.XLim = XLim;
                    ax.YLim = YLim;
                else 
                    ax.XLim = [0 vectorTiempo(end)]; 
                    ax.YLim = [-Inf Inf];
                end
            else
                cla(ax);
            end

            %%% Marcaje de eventos
            eventos = strtrim(app.eventosEditField.Value);
            if(~isempty(eventos))
                S = eventos;
                fin=num2str(vectorTiempo(end)); 
                S = replace(S,'end',fin);
                [mat,~] = regexp(S,'\[[0-9\:\.\,]+\]','match','tokens');
                app.eventosMuestras = cell([1 length(mat)]);
                app.eventosInstantes = cell([1 length(mat)]);
                app.eventosMarcadores = cell([1 length(mat)]);                
                Y1 = app.SeriesUIAxes.YTick(1) - (app.SeriesUIAxes.YTick(2)-app.SeriesUIAxes.YTick(1))  ;
                Y2 = app.SeriesUIAxes.YTick(end) + (app.SeriesUIAxes.YTick(2)-app.SeriesUIAxes.YTick(1)) ;
                for ix = 1:length(mat)
                    X = eval(mat{ix});
                    app.eventosInstantes{ix} =  zeros(2,length(X));
                    app.eventosInstantes{ix}(1,:) = X;
                    app.eventosInstantes{ix}(2,:) = X;                    
                    app.eventosMuestras{ix} = app.segundosAMuestras(X);
                    app.eventosMarcadores{ix} =  zeros(2,length(app.eventosInstantes{ix}));
                    app.eventosMarcadores{ix}(1,:) = Y1;
                    app.eventosMarcadores{ix}(2,:) = Y2;
                    hold(ax,'on');
                    C = lines(ix);
                    plot(ax,app.eventosInstantes{ix},app.eventosMarcadores{ix},'Color',C(ix,:), 'LineStyle',':');
                    hold(ax,'off');            
                end
            end
        end

        function actualizarParametrosFiltros(app)
            app.pasoBajoFrecuenciaCorte =  app.datosDeConfiguracion.SamplingRate / 2 - app.epsilonParaFiltro;
            app.pasoAltoFrecuenciaCorte =  app.epsilonParaFiltro;
            app.pasoBandaFrecuenciasCorte =  [app.pasoAltoFrecuenciaCorte app.pasoBajoFrecuenciaCorte];
            app.PasoBajoSlider.Value = app.asignarValorSeguroSlider(app.PasoBajoSlider,app.pasoBajoFrecuenciaCorte); 
            app.PasoAltoSlider.Value = app.asignarValorSeguroSlider(app.PasoAltoSlider,app.pasoAltoFrecuenciaCorte);
                       
            %%% Asegurar que si se altera el listbox, actualizar estas líneas:
            app.pasoBajoEtiqueta = app.OpcionalFiltroDeDatosListBox.Items{2};
            app.pasoAltoEtiqueta = app.OpcionalFiltroDeDatosListBox.Items{3};
            app.pasoBandaEtiqueta = app.OpcionalFiltroDeDatosListBox.Items{4};
            app.drifterEtiqueta = app.OpcionalFiltroDeDatosListBox.Items{5};
            app.waveletEtiqueta = app.OpcionalFiltroDeDatosListBox.Items{6};
        end
      
        function actualizarFiguraSeparada(app)
            if isempty(app.tiempoSegundosDatos) && isempty(app.datosDeSerie); return; end
            app.figuraExterna = true;
            f = figure;
            ax = gca(f);
            switch app.SeriesUIAxes.Visible
                case 'on'
                    app.actualizarSeries(ax);
                otherwise
                    app.actualizarImagenes(ax);
            end
        end
  
        function actualizarFiltrado(app)
            LI = app.epsilonParaFiltro;
            LS = floor(10*(app.datosDeConfiguracion.SamplingRate / 2))/10; % solo un decimal
            app.PasoBajoSlider.Limits = [LI,LS];
            app.PasoAltoSlider.Limits = [LI,LS];
            app.verificarLimitesFiltro();
            app.PasoBajoSlider.Visible = 'off';
            app.PasoAltoSlider.Visible = 'off';
            app.cambiarVisibilidadEditField(app.CorteSuperiorEditField,app.CortesuperiorHzEditFieldLabel,'off');
            app.cambiarVisibilidadEditField(app.corteInferiorEditField,app.CorteinferiorHzEditFieldLabel,'off');
            switch(app.OpcionalFiltroDeDatosListBox.Value)
                case app.pasoBajoEtiqueta
                    app.pasoBajoFrecuenciaCorte = app.PasoBajoSlider.Value;
                    app.PasoBajoSlider.Visible = 'on';
                    app.PasoAltoSlider.Visible = 'off';
                    app.cambiarVisibilidadEditField(app.CorteSuperiorEditField,app.CortesuperiorHzEditFieldLabel,'on');
                    app.cambiarVisibilidadEditField(app.corteInferiorEditField,app.CorteinferiorHzEditFieldLabel,'off');
                case app.pasoAltoEtiqueta    
                    app.pasoAltoFrecuenciaCorte = app.PasoAltoSlider.Value;
                    app.PasoBajoSlider.Visible = 'off';
                    app.PasoAltoSlider.Visible = 'on';
                    app.cambiarVisibilidadEditField(app.CorteSuperiorEditField,app.CortesuperiorHzEditFieldLabel,'off');
                    app.cambiarVisibilidadEditField(app.corteInferiorEditField,app.CorteinferiorHzEditFieldLabel,'on');
                case app.pasoBandaEtiqueta
                    app.pasoBajoFrecuenciaCorte = app.PasoBajoSlider.Value;
                    app.pasoAltoFrecuenciaCorte = app.PasoAltoSlider.Value;                    
                    app.PasoAltoSlider.Visible = 'on';
                    app.PasoBajoSlider.Visible = 'on';
                    app.cambiarVisibilidadEditField(app.CorteSuperiorEditField,app.CortesuperiorHzEditFieldLabel,'on');
                    app.cambiarVisibilidadEditField(app.corteInferiorEditField,app.CorteinferiorHzEditFieldLabel,'on');
                case app.drifterEtiqueta
                case app.waveletEtiqueta
                otherwise
            end
            app.actualizarUIAxes();
        end
  
        function actualizarImagenes(app,ax)
            app.componenteImagenElegida = app.ComponenteImagenButtonGroup.SelectedObject.Tag;
            app.prepararImagen(app.aplicacionTriangulacionMetodoLineal());
            app.presentarImagenesRejilla(ax); 
        end     

        function actualizarUIAxes(app,varargin)
            if isempty(app.tiempoSegundosDatos) && isempty(app.datosDeSerie); return; end
            app.figuraExterna = false;
            switch app.UIAxesElegido 
                case app.UIAxesSeriesElegidaEtiqueta
                    app.actualizarSeries(app.SeriesUIAxes,varargin);
                otherwise
                    app.actualizarImagenes(app.ImagenesUIAxes);
            end
        end
        
        function imagenAjustada = aplicacionTriangulacionMetodoLineal(app)
            %% dataImagen --> vector columna(:), coordenadasGrid --> [xi(:), yi(:), zi(:) ]
            
            imagenAjustada = [];
            switch app.eleccionTipoSerie
                case app.indiceSerieWL1  
                    return;
                case app.indiceSerieWL2  
                    return;
                case app.indiceSerieHbO  
                    imagenCalculadaTiempoxNodos = app.valoresCalculadosParaNodosFEM.HbO;
                case app.indiceSerieHbR  
                    imagenCalculadaTiempoxNodos = app.valoresCalculadosParaNodosFEM.HbR;
                case app.indiceSerieHbTot  
                    imagenCalculadaTiempoxNodos = app.valoresCalculadosParaNodosFEM.HbTot;
                case app.indiceSerieHbSat  
                    imagenCalculadaTiempoxNodos = app.valoresCalculadosParaNodosFEM.HbSat;
                case app.indiceSerieHbSatLog  
                    imagenCalculadaTiempoxNodos = app.valoresCalculadosParaNodosFEM.HbSatLog;
                case app.indiceSerieODWL1  
                    imagenCalculadaTiempoxNodos = app.valoresCalculadosParaNodosFEM.ODWL1;
                case app.indiceSerieODWL2  
                    imagenCalculadaTiempoxNodos = app.valoresCalculadosParaNodosFEM.ODWL2;
                otherwise
                    return;
            end
            app.enProcesamientoButton.Visible = 'on';
            app.botonActualEnProcesamiento(app.enProcesamientoButton);
            %%% En este momento, dataSeries es complejo, porque
            %%% valoresCalculadosParaNodosFEM.x es complejo por la aplicación de la matriz jacobiana compleja. 
            %%% TODO: ver que hacer para que dataSeries sea real
            %%% considerar las opciones de magnitud, parte real, o alguna función aplicada a 
            %%% valoresCalculadosParaNodosFEM.x para que los valores tengan un sentido físico 

            app.botonActualEnProcesamientoActualizarProgreso(1,3);
            
            muestra = app.segundosAMuestras(app.tiempoElegido);
            switch app.componenteImagenElegida
                case app.ComponenteImagenMagnitudEtiqueta 
                    dataSeries = abs(imagenCalculadaTiempoxNodos(muestra,:));
                case app.ComponenteImagenFaseEtiqueta 
                    dataSeries = (angle(imagenCalculadaTiempoxNodos(muestra,:))); % considerar unwrap a futuro
                case app.ComponenteImagenRealEtiqueta 
                    dataSeries = real(imagenCalculadaTiempoxNodos(muestra,:));
                case app.ComponenteImagenImaginariaEtiqueta 
                    dataSeries = imag(imagenCalculadaTiempoxNodos(muestra,:));
                otherwise
                    return;
            end
            dataSeries = dataSeries(:);
            imagenAjustada = zeros(size(app.coordenadasGrid,1),1);
            %%% En este punto, la triangulación y demás parámetros ya deben
            %%% estar calculados (calcularImagen)
            TRI = app.triangulacionDelaunay(app.tsearchnIndicesCoberturaSimple(app.indicesValidosCoberturaSimple),:);
            dataSeriesSalida = app.corregirDataSeries(dataSeries);
            app.botonActualEnProcesamientoActualizarProgreso(2,3);            
            imagenAjustada(app.indicesValidosCoberturaSimple) = ... 
                sum(app.tsearchnCoordenadasBaricentricas(app.indicesValidosCoberturaSimple,:).*dataSeriesSalida(TRI),2);
            imagenAjustada = reshape(imagenAjustada,app.dimensionesVolumenImagen);            
            app.botonActualEnProcesamientoActualizarProgreso(3,3);
            pause(0.5);
            app.enProcesamientoButton.Visible = 'off'; 
            app.botonActualEnProcesamientoActualizarProgreso(0,3);
        end

        function valorSalida = asignarValorSeguroSlider(~,slider,valorEntrada)
            valorSalida = max(min(slider.Limits),valorEntrada);
            valorSalida = min(max(slider.Limits),valorSalida);
        end
        
        function matrizBlanqueada = blanquearMatrizJacobianaZCA(~,M)
            epsilon = 1e-6;
            matriz = M';
            mediaMatriz = mean(matriz); 
            matrizSinMedia = bsxfun(@minus, matriz, mediaMatriz);
            productoInterno = matrizSinMedia'*matrizSinMedia; %%% (CanalesxCanales)
            [U,S,~] = svd(productoInterno);
            matrizBlanqueante = sqrt(size(matrizSinMedia,1)-1)*U*sqrtm(inv(S + eye(size(S))*epsilon))*U';
            matrizBlanqueada = matriz*matrizBlanqueante;  
            matrizBlanqueada = matrizBlanqueada';
        end

        function botonActualEnProcesamiento(app,boton)
            app.botonActualEnProcesamientoStruct.boton = boton;
            app.botonActualEnProcesamientoStruct.text = boton.Text;
            app.botonActualEnProcesamientoStruct.boton.Text = 'Procesando...';
            app.botonActualEnProcesamientoStruct.boton.IconAlignment = 'bottom';
            app.botonActualEnProcesamientoStruct.wbar = permute(repmat(app.botonActualEnProcesamientoStruct.boton.BackgroundColor,15,1,200),[1,3,2]);
            app.botonActualEnProcesamientoStruct.wbar([1,end],:,:) = 0;
            app.botonActualEnProcesamientoStruct.wbar(:,[1,end],:) = 0;
            app.botonActualEnProcesamientoStruct.boton.Icon = app.botonActualEnProcesamientoStruct.wbar;
            pause(0.5);
        end

        function botonActualEnProcesamientoActualizarProgreso(app,ix,n)
            currentProg = min(round((size(app.botonActualEnProcesamientoStruct.wbar,2)-2)*(ix/n)),size(app.botonActualEnProcesamientoStruct.wbar,2)-2);
            app.botonActualEnProcesamientoStruct.boton.Icon(2:end-1, 2:currentProg+1, 1) = app.royalBlueColor(1);
            app.botonActualEnProcesamientoStruct.boton.Icon(2:end-1, 2:currentProg+1, 2) = app.royalBlueColor(2);
            app.botonActualEnProcesamientoStruct.boton.Icon(2:end-1, 2:currentProg+1, 3) = app.royalBlueColor(3);
            pause(0.5);
        end

        function botonActualFinProcesamiento(app)
            app.botonActualEnProcesamientoStruct.boton.Icon = '';
            app.botonActualEnProcesamientoStruct.boton.Text = app.botonActualEnProcesamientoStruct.text;
        end
        
        function calcularCanalesAMostrar(app)
            canales = zeros(1,app.datosDeConfiguracion.channel_N);
            nodos = findall(app.VerFuentesDetectoresTree,"Type","uitreenode");
            nodosTildados = ismember(nodos,app.VerFuentesDetectoresTree.CheckedNodes);
            indiceComienzoOptodos = 3;
            indiceFinOptodos = app.maximoFuentesAplicacion+2;
            fuentesSeleccion = nodosTildados(indiceComienzoOptodos:indiceFinOptodos);
            indiceComienzoOptodos = indiceFinOptodos + 1;
            indiceFinOptodos = indiceComienzoOptodos +  app.maximoDetectoresAplicacion - 1;
            detectoresSeleccion = nodosTildados(indiceComienzoOptodos:indiceFinOptodos);
            
            ik = 1;
            for fuente = 1:app.datosDeConfiguracion.source_N
                for detector = 1:(app.datosDeConfiguracion.detector_N)
                %for detector = 1:(app.datosDeConfiguracion.detector_N + app.usoReferencia)
                    canales(ik) = fuentesSeleccion(fuente) && detectoresSeleccion(detector) ;
                    ik = ik + 1;
                end
            end

            app.canalesMostrados = find(canales);
            app.canalesOcultos = setdiff(1:app.datosDeConfiguracion.channel_N,app.canalesMostrados);
        end

        function datosFiltrados = calcularDatosFiltrados(app,datosEntrada)
            datosFiltrados = zeros(size(datosEntrada));
            switch(app.OpcionalFiltroDeDatosListBox.Value)
                case app.pasoBajoEtiqueta                        
                    datosFiltrados(:,app.canalesMostrados) = app.filtradoDatosPasoBajo(datosEntrada(:,app.canalesMostrados));
                case app.pasoAltoEtiqueta
                    datosFiltrados(:,app.canalesMostrados) = app.filtradoDatosPasoAlto(datosEntrada(:,app.canalesMostrados));
                case app.pasoBandaEtiqueta
                    datosFiltrados(:,app.canalesMostrados) = app.filtradoDatosPasoBanda(datosEntrada(:,app.canalesMostrados));
                %case 'DRIFTER'
                %case 'Wavelet'
                otherwise
                   datosFiltrados(:,app.canalesMostrados) = datosEntrada(:,app.canalesMostrados);
            end
        end
    
        function calcularHemoglobina(app)
            %%% Coeficientes de extinción molar
             ext = zeros(2,2);
             ext(1,1) = 586;     %oxy-Hb, 760 nm
             ext(2,1) = 974;     %oxy-Hb, 830 nm
             ext(1,2) = 1548.52; %deoxy-Hb, 760 nm
             ext(2,2) = 693.04;  %deoxy-Hb, 830 nm
             ext = ext*log(10); 
             invext = inv(ext);
             %%% HbO
             app.datosDeSerie{app.indiceSerieHbO} = real(invext(1,1)*app.datosDeSerie{app.indiceSerieWL1} + invext(1,2)*app.datosDeSerie{app.indiceSerieWL2});
             %%% HbR
             app.datosDeSerie{app.indiceSerieHbR} =  real(invext(2,1)*app.datosDeSerie{app.indiceSerieWL1} + invext(2,2)*app.datosDeSerie{app.indiceSerieWL2});
             app.datosDeSerie{app.indiceSerieHbTot} = app.datosDeSerie{app.indiceSerieHbO} + app.datosDeSerie{app.indiceSerieHbR};
             app.datosDeSerie{app.indiceSerieHbSat} = app.datosDeSerie{app.indiceSerieHbO} ./ app.datosDeSerie{app.indiceSerieHbTot};
             app.calcularSaturacionLogistica();
             app.datosDeSerie{app.indiceSerieODWL1} = hmrIntensity2OD(app.datosDeSerie{app.indiceSerieWL1});    
             app.datosDeSerie{app.indiceSerieODWL2} = hmrIntensity2OD(app.datosDeSerie{app.indiceSerieWL2});    
        end

        function calcularImagen(app)
            %%% La jacobiana que se lee es una matriz de C x 2*N, donde C
            %%% es el número de canales (sin tomar en cuenta el que se
            %%% repite como referencia) y N es el número de nodos del FEM
            %%% Conceptualmente, la jacobiana es una matriz compleja. Aquí 
            %%% se ha elegido representarla de forma que las primeras N 
            %%% columnas de la matriz corresponden la parte real de los
            %%% números complejos, mientras que las segundas N columnas
            %%% corresponden a los valores imaginarios.
            %%% En esta implementación, utilizaré todos los valores
            %%% (reales,imaginarios) no como en NAVI.
            %%% El algoritmo original escala las filas a la suma de los
            %%% valores de sus columnas (nodos)
            %%% ¿Regularización?¿Normalización?. Aquí aplicaremos el
            %%% whitening ZCA de la matriz Jacobiana, colocando en las
            %%% columnas los canales.
            app.nombreCompletoFicheroJacobiana = fullfile(app.rutaFicheroFEM,'w.FEM');
            app.nombreCompletoFicheroFactoresDeteccionPrecalculados = fullfile(app.rutaFicheroFEM,'det.FEM');
            app.nombreCompletoFicheroSingulares = fullfile(app.rutaFicheroConfig,'Wsv.FEM');
            try
                app.matrizJacobiana = load(app.nombreCompletoFicheroJacobiana, '-mat');
                app.matrizJacobiana = app.matrizJacobiana.w;
                numeroColumnasJacobiana = size(app.matrizJacobiana,2);
                numeroNodosFEM = numeroColumnasJacobiana/2;
                app.factoresDeteccionPrecalculados = load(app.nombreCompletoFicheroFactoresDeteccionPrecalculados, '-ascii');
                primeraMitadColumnas = app.matrizJacobiana(:,1:numeroNodosFEM);
                segundaMitadColumnas = app.matrizJacobiana(:,(1+numeroNodosFEM):end);
                app.matrizJacobiana = complex(primeraMitadColumnas,segundaMitadColumnas);
                %%% Blanqueamiento  de la matriz (whitening)
                app.matrizJacobianaBlanqueada = app.blanquearMatrizJacobianaZCA(app.matrizJacobiana);
                %%% Factores de normalización de valores de canales
                factorEscalaJacobiana = sum(abs(app.matrizJacobiana),2);
                %%% factores detección (numero == numero de canales)
                app.factoresDeteccionEscalados = app.factoresDeteccionPrecalculados ./ factorEscalaJacobiana(:);
                app.factoresDeteccionEscalados = reshape(app.factoresDeteccionEscalados(:),[1 length(app.factoresDeteccionEscalados)]);
                %%% Valores de hemoglobina en nodos FEM
                app.botonActualEnProcesamientoActualizarProgreso(1,3);
                app.datosDeSerieFiltrados{app.indiceSerieHbO} = app.calcularDatosFiltrados(app.datosDeSerie{app.indiceSerieHbO});
                app.datosDeSerieFiltrados{app.indiceSerieHbR} = app.calcularDatosFiltrados(app.datosDeSerie{app.indiceSerieHbR});
                app.datosDeSerieFiltrados{app.indiceSerieHbSatLog} = app.calcularDatosFiltrados(app.datosDeSerie{app.indiceSerieHbSatLog});
                
                app.valoresCalculadosParaNodosFEM.HbO = (repmat(app.factoresDeteccionEscalados,[size(app.datosDeSerieFiltrados{app.indiceSerieHbO},1),1]) .* app.datosDeSerieFiltrados{app.indiceSerieHbO}) * app.matrizJacobianaBlanqueada ;
                app.valoresCalculadosParaNodosFEM.HbR = (repmat(app.factoresDeteccionEscalados,[size(app.datosDeSerieFiltrados{app.indiceSerieHbR},1),1]) .* app.datosDeSerieFiltrados{app.indiceSerieHbR}) * app.matrizJacobianaBlanqueada ;          
                app.valoresCalculadosParaNodosFEM.HbTot = app.valoresCalculadosParaNodosFEM.HbO + app.valoresCalculadosParaNodosFEM.HbR;
                app.valoresCalculadosParaNodosFEM.HbSat = (app.valoresCalculadosParaNodosFEM.HbO./app.valoresCalculadosParaNodosFEM.HbTot); 
                app.valoresCalculadosParaNodosFEM.HbSatLog = (repmat(app.factoresDeteccionEscalados,[size(app.datosDeSerieFiltrados{app.indiceSerieHbSatLog},1),1]) .* app.datosDeSerieFiltrados{app.indiceSerieHbSatLog}) * app.matrizJacobianaBlanqueada ; 
                
                if (app.RestarMediaTemporalVolumenes)
                    app.valoresCalculadosParaNodosFEM.HbO = app.valoresCalculadosParaNodosFEM.HbO - mean(app.valoresCalculadosParaNodosFEM.HbO);
                    app.valoresCalculadosParaNodosFEM.HbR = app.valoresCalculadosParaNodosFEM.HbR - mean(app.valoresCalculadosParaNodosFEM.HbR);
                    app.valoresCalculadosParaNodosFEM.HbTot = app.valoresCalculadosParaNodosFEM.HbTot - mean(app.valoresCalculadosParaNodosFEM.HbTot);
                    app.valoresCalculadosParaNodosFEM.HbSat = app.valoresCalculadosParaNodosFEM.HbSat - mean(app.valoresCalculadosParaNodosFEM.HbSat);
                    app.valoresCalculadosParaNodosFEM.HbSatLog = app.valoresCalculadosParaNodosFEM.HbSatLog - mean(app.valoresCalculadosParaNodosFEM.HbSatLog);
                end
                
                app.botonActualEnProcesamientoActualizarProgreso(2,3);
                app.calcularTriangulacion();           
                app.botonActualEnProcesamientoActualizarProgreso(3,3);
            catch ME
                errordlg([ME.message,'(calcularImagen)']);
            end
            
        end

        function calcularParametrosFEM(app)  
            try 
                coordenadasDeNodos = load(fullfile(app.rutaFicheroFEM, 'coords.FEM'), '-ascii');
            catch ME
                errordlg([ME.message,'(calcularParametrosFEM)']);
                return;
            end
        
            x0 = coordenadasDeNodos(:,2)';
            y0 = coordenadasDeNodos(:,3)';
            
            xmax = max(x0)-0.1;
            ymax = max(y0)-0.1;
            xmin = min(x0)+0.1;
            ymin = min(y0)+0.1;
            dx0=xmax-xmin;
            dy0=ymax-ymin;
           
            if(size(coordenadasDeNodos,2)>3)
                z0 = coordenadasDeNodos(:,4)';
                zmax = max(z0)-0.1;
                zmin = min(z0)+0.1;
                dz0=zmax-zmin;
                d_max=max([dx0, dy0, dz0]);
                zmin=zmin-(d_max-dz0);
            else 
                z0=[];
                d_max=max(dx0, dy0);
            end
        
            if (contains(app.rutaFicheroFEM,'Frontal_Cortex')) || (contains(app.rutaFicheroFEM,'box2'))
                ymax0=ymax+(d_max-dy0);
                xmax0=xmax;
                xmin0=xmin;
                ymin0=ymin;
            elseif(contains(app.rutaFicheroFEM,'Occipital_Cortex')) || (contains(app.rutaFicheroFEM,'box8'))
                ymin0=ymin-(d_max-dy0);
                xmax0=xmax;
                xmin0=xmin;
                ymax0=ymax;
            elseif (contains(app.rutaFicheroFEM,'Left_Motor')) || (contains(app.rutaFicheroFEM,'box14'))     
                ymax0=ymax+(d_max-dy0);
                xmax0=xmax;
                xmin0=xmin;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'Right_Motor')) || (contains(app.rutaFicheroFEM,'box15'))     
                ymax0=ymax+(d_max-dy0);
                xmax0=xmax;
                xmin0=xmin;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'FL_newcap')) || (contains(app.rutaFicheroFEM,'box3'))
                xmin0=xmin-(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmax0=xmax;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'FR_newcap')) || (contains(app.rutaFicheroFEM,'box1'))
                xmax0=xmax+(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmin0=xmin;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'box13'))
                xmin0=xmin-(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmax0=xmax;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'box11'))
                xmax0=xmax+(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmin0=xmin;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'BL_newcap')) || (contains(app.rutaFicheroFEM,'box5'))
                xmin0=xmin-(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmax0=xmax;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'BR_newcap')) || (contains(app.rutaFicheroFEM,'box4'))
                xmax0=xmax+(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmin0=xmin;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'box61'))
                xmin0=xmin-(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmax0=xmax;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'box62'))
                xmin0=xmin-(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmax0=xmax;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'box71'))
                xmax0=xmax+(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmin0=xmin;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'box72'))
                xmax0=xmax+(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmin0=xmin;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'box10'))
                xmax0=xmax+(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmin0=xmin;
                ymin0=ymin;
            elseif (contains(app.rutaFicheroFEM,'box12'))
                xmin0=xmin-(d_max-dx0);
                ymax0=ymax+(d_max-dy0);
                xmax0=xmax;
                ymin0=ymin;
            else
                xmax0=xmax+(d_max-dx0)*0.5;
                xmin0=xmin-(d_max-dx0)*0.5;
                ymax0=ymax+(d_max-dy0)*0.5;
                ymin0=ymin-(d_max-dy0)*0.5;
            end
            
            xmax=xmax0;
            xmin=xmin0;
            ymax=ymax0;
            ymin=ymin0;
            
            %% dimensionesVolumenImagen: 40x40X40, 64x64x64, 128x128x128, ...
            dx = (xmax - xmin)/(app.dimensionesVolumenImagen(1) - 1);  
            dy = (ymax - ymin)/(app.dimensionesVolumenImagen(2) - 1);   
            
            if size(coordenadasDeNodos,2)<=3
                [xi,yi] = meshgrid(xmin:dx:xmax, ymin:dy:ymax);
                zi=1;
                xs=0; ys=0; zs=0;
            else
                dz = (zmax - zmin)/(app.dimensionesVolumenImagen(3) - 1);  
                [xi,yi,zi] = meshgrid(xmin:dx:xmax, ymin:dy:ymax,zmin:dz:zmax);
                xs=xmin:dx:xmax; ys=ymin:dy:ymax; zs=zmin:dz:zmax;   
            end

            app.coordenadasNodos = [x0(:), y0(:), z0(:)]; 
            app.coordenadasGrid = [xi(:), yi(:), zi(:)]; 
        end

        function calcularSaturacionLogistica(app)  
            D_SCN = app.SaturacionLogistica_SCN(2) - app.SaturacionLogistica_SCN(1);
            %    Se ha omitido la resta de la media aquí
            HbO = app.datosDeSerie{app.indiceSerieHbO};
            HbR = app.datosDeSerie{app.indiceSerieHbR};
            %    De la ecuacion de saturacion HbO/(HbO+HbR) se desprende que
            %    u_HbR / u_HbO = (T_SCN)^-1 -  1, donde u_HbR es la esperanza de 
            %    la serie HbR y u_HbO la esperanza de la serie HbO (Bajo
            %    condiciones normales (CN), se espera que la saturacion este en la
            %    saturacion normal esperada (T_SCN), lo cual se mantendria cierto
            %    si los valores de las series HbO y HbR fueran constantes y respectivamente u_HbO y u_HbR. 
            %    Se coloca la u_HbO en funcion de u_HbR y T_SCN. u_HbR se coloca
            %    arbitrariamente en un número no negativo (HbR > 0)
            u_HbR = 3*std(HbR)+eps;
            u_HbO = u_HbR / ((app.SaturacionLogistica_T_SCN)^-1 - 1);
            n_HbO = bsxfun(@plus,HbO, u_HbO);
            n_HbR = bsxfun(@plus,HbR, u_HbR);
            % Calculo de diferencias de las series. Esperanza y desviacion de las
            % diferencias
            D = n_HbO - n_HbR;
            u_D = mean(D);
            s_D = std(D);             
            %%% Que pasa si s_D es cero (caso patologico)
            s_D(s_D==0) = eps; 
            % Ajuste logistico de saturacion mediante la formula
            %    L(d) = k1 * { 1/ [1+exp{-k2((d-k3)/s_D)} ] + k4.
            %    Asumimos:
            %       k1 = D_SCN (amplitud del rango de la curva logistica)
            %       k2 = 1 (control de pendiente de la curva)
            %       d-k3 == 0 , valor donde la curva == 0.5 
            %       k4 = SCN(1) , desplazamiento de la asintota inferior de la curva
            %    Nos interesa conocer el valor de k3 cuando d == u_D y L(d) =
            %    T_SCN, para lo cual usamos la inversa de la ecuacion:
            %       k3 = d + ln [ {k1 / [L(d) - k4]} - 1 ] * s_D / k2            
            k3 = u_D + log((D_SCN/(app.SaturacionLogistica_T_SCN-app.SaturacionLogistica_SCN(1)))-1)*s_D/app.SaturacionLogistica_k2;
            % Calculo de la saturacion en base a la serie de diferencias
            app.datosDeSerie{app.indiceSerieHbSatLog} = D_SCN * (1./(1+exp(-app.SaturacionLogistica_k2*bsxfun(@rdivide,(bsxfun(@minus,D,k3)),s_D)))) + app.SaturacionLogistica_SCN(1);
        end

        function calcularTriangulacion(app)
            app.corregirCoordenadas();
            app.triangulacionDelaunay = delaunayn(app.tsearchnCoordenadasReordenadasCorregidas);
           [app.tsearchnIndicesCoberturaSimple,app.tsearchnCoordenadasBaricentricas] = ...
                tsearchn(app.tsearchnCoordenadasReordenadasCorregidas,app.triangulacionDelaunay,app.coordenadasGrid);
           numeroElementosGrid = size(app.coordenadasGrid,1); %%  las filas de coordenadasGrid corresponden al número de elementos del grid
           app.indicesValidosCoberturaSimple = find(~isnan(app.tsearchnIndicesCoberturaSimple(1:numeroElementosGrid)));
        end

        function cambiarVisibilidadEditField(~,campo,etiqueta,valorVisibilidad)
            campo.Visible = valorVisibilidad;
            etiqueta.Visible = valorVisibilidad;
        end
       
        function optodos = canalesAOptodos(app,listaCanales)
            optodos = arrayfun(@(x) 1+[floor((x-1)/app.numeroFuentes),mod((x-1),app.numeroFuentes)],listaCanales,'UniformOutput',false);
        end

        function corregirCoordenadas(app)
            [app.tsearchnCoordenadasReordenadasCorregidas, app.tsearchnIndicesReordenamiento] = sortrows(app.coordenadasNodos);
            %%% Gestión de índices duplicados (necesario para delaunay) 
            %dataSeriesSinDuplicados = dataSeries(indicesReordenamiento);
            app.tsearchnDiferenciasIndices = all(diff(app.tsearchnCoordenadasReordenadasCorregidas)'==0);
            if any(app.tsearchnDiferenciasIndices)
                % Datos duplicados en coordenadas de grid (triangulacionMetodoLineal). 
                app.tsearchnDiferenciasIndices = [0 diferenciasIndices];
                diferenciasIndices1 = diff(app.tsearchnDiferenciasIndices);
                app.tsearchnVectorIndiceComienzo = find(diferenciasIndices1==1);
                app.tsearchnVectorIndiceFinal = find(diferenciasIndices1==-1);
                % Completar si el último término comienza en posición "end" 
                if app.tsearchnVectorIndiceComienzo(end) == length(diferenciasIndices1) 
                    app.tsearchnVectorIndiceFinal = [app.tsearchnVectorIndiceFinal app.tsearchnVectorIndiceComienzo(end)+1];
                end
                app.tsearchnCoordenadasReordenadasCorregidas = ...
                    app.tsearchnCoordenadasReordenadasCorregidas(~app.tsearchnDiferenciasIndices(2:end),:);
            end
        end

        function dataSeriesCorregida = corregirDataSeries(app,dataSeries)
            %%% Gestión de índices duplicados (necesario para delaunay) 
            dataSeriesSinDuplicados = dataSeries(app.tsearchnIndicesReordenamiento);
            if any(app.tsearchnDiferenciasIndices)
                % Uso de promedio de valores de molécula en nodos de FEM
                for ix = 1 : length(app.tsearchnVectorIndiceComienzo)
                    dataSeriesSinDuplicados(app.tsearchnVectorIndiceFinal(ix)) = ...
                        mean(dataSeriesSinDuplicados(app.tsearchnVectorIndiceComienzo(ix):app.tsearchnVectorIndiceFinal(ix)));
                end
                dataSeriesCorregida = dataSeriesSinDuplicados(~app.tsearchnIndicesReordenamiento(2:end));
            else
                dataSeriesCorregida = dataSeriesSinDuplicados;
            end
        end
        
        function valor = extraerValorConfiguracion(~,linea)
            s = split(linea,{'=',';'});
            valor = s{2};
        end

        function valor = extraerSubcadenaDeValorConfiguracionAvanzada(app,etiqueta)
            %%% Tomar de configuración avanzada
            sep1 = characterListPattern('= ');
            sep2 = characterListPattern('; ');            
            pat = etiqueta + asManyOfPattern(sep1) + asManyOfPattern(digitsPattern) + asManyOfPattern(sep2);
            lineaConfiguracion = extract(strjoin(string(app.ConfiguracionAvanzadaTextArea.Value)),pat);
            valor = strtrim(extractBetween(lineaConfiguracion,'=',';'));
        end   

        function valor = extraerValorNumericoConfiguracionAvanzada(app,etiqueta)
            valor = eval(extraerSubcadenaDeValorConfiguracionAvanzada(app,etiqueta));
        end   
                                
        function datosSalida = filtradoDatosPasoAlto(app,datosEntrada)
            if (isempty(datosEntrada))
                datosSalida = [];
                return
            end
            muestrasDeRelleno = app.segundosAMuestras(app.segundosDeRellenoParaFiltros); 
            rango_relleno_inicio = muestrasDeRelleno:-1:1;
            rango_relleno_final = app.datosDeConfiguracion.time_point_N:-1:(app.datosDeConfiguracion.time_point_N-muestrasDeRelleno-1);
            datos_relleno_inicio = datosEntrada(rango_relleno_inicio,:);
            datos_relleno_final = datosEntrada(rango_relleno_final,:);
            mediaDatosEntrada = mean(datosEntrada);
            SB = app.pasoAltoFrecuenciaCorte;
            SB = app.pasoAltoFrecuenciaCorte - min(0.1,SB-app.epsilonParaFiltro/2);
            dbutter = designfilt('highpassiir','PassbandFrequency',app.pasoAltoFrecuenciaCorte,...  
                'StopbandFrequency', SB , ...
                'PassbandRipple',1,...
                'StopbandAttenuation',60,'SampleRate',app.datosDeConfiguracion.SamplingRate,'DesignMethod','ellip');
            datosSalida = filtfilt(dbutter,[datos_relleno_inicio;datosEntrada;datos_relleno_final]);
            datosSalida = datosSalida((length(rango_relleno_inicio)+1):(end-length(rango_relleno_final)),:);
            datosSalida = datosSalida + repmat(mediaDatosEntrada,[size(datosSalida,1),1]);
        end

        function datosSalida = filtradoDatosPasoBajo(app,datosEntrada)
            muestrasDeRelleno = app.segundosAMuestras(app.segundosDeRellenoParaFiltros); 
            SB = (app.datosDeConfiguracion.SamplingRate/2 -app.epsilonParaFiltro - app.pasoBajoFrecuenciaCorte);
            SB = app.pasoBajoFrecuenciaCorte + min(0.1,SB);
            dbutter = designfilt('lowpassiir','PassbandFrequency',app.pasoBajoFrecuenciaCorte,...  
                'StopbandFrequency', SB , ...
                'PassbandRipple',1,...
                'StopbandAttenuation',60,'SampleRate',app.datosDeConfiguracion.SamplingRate,'DesignMethod','butter');
            rango_relleno_inicio = muestrasDeRelleno:-1:1;
            rango_relleno_final = app.datosDeConfiguracion.time_point_N:-1:(app.datosDeConfiguracion.time_point_N-muestrasDeRelleno-1);
            datos_relleno_inicio = datosEntrada(rango_relleno_inicio,:);
            datos_relleno_final = datosEntrada(rango_relleno_final,:);
            datosSalida = filtfilt(dbutter,[datos_relleno_inicio;datosEntrada;datos_relleno_final]);
            datosSalida = datosSalida((length(rango_relleno_inicio)+1):(end-length(rango_relleno_final)),:);
        end

        function datosSalida = filtradoDatosPasoBanda(app,datosEntrada)
            datosSalida = app.filtradoDatosPasoBajo(datosEntrada);
            datosSalida = app.filtradoDatosPasoAlto(datosSalida);
        end

        function guardarDataInfo(app)
            dataInfo = app.G_dataInfo;
            try
                save(app.G_dataInfo.dataInfo_file, 'dataInfo');
            catch ME
                errordlg([ME.message,'(guardarDataInfo)'])
            end
        end

        function inicializarDataInfo(app)
                app.G_dataInfo.ImagerType = 1; % Cerebro
                app.G_dataInfo.BatchProcessing=0;
                app.G_dataInfo.FilteringProcessed=0;
                app.G_dataInfo.CVProcessed=0;
                app.G_dataInfo.NormalizationProcessed=0;
                app.G_dataInfo.ReconstructionProcessed=0;
                app.G_dataInfo.PCA_SavedResults_N=0;
                app.G_dataInfo.MSA_SavedResults_N=0;
                app.G_dataInfo.ICA_SavedResults_N=0;
                app.G_dataInfo.GLM_SavedResults_N=0;
                app.G_dataInfo.PSD_SavedResults_N=0;
                app.G_dataInfo.CSD_SavedResults_N=0;
                app.G_dataInfo.COH_SavedResults_N=0;
                app.G_dataInfo.COR_SavedResults_N=0;
                app.G_dataInfo.Slope_SavedResults_N=0;
                app.G_dataInfo.Delay_SavedResults_N=0;
                app.G_dataInfo.Area_SavedResults_N=0;
                app.G_dataInfo.AUTO_SavedResults_N=0;
                app.G_dataInfo.BAIs_SavedResults_N=0;
                app.G_dataInfo.BADs_SavedResults_N=0;
                % Eliminada RawDataInfo. Ir eliminando redundancias innecesarias.
                app.G_dataInfo.dataInfo_file = fullfile(app.rutaFicheroConfig,[app.datosDeConfiguracion.raw_data_name '_dataInfo.mat']);    
                app.guardarDataInfo();
        end
      
        function lecturaDatos(app)
                app.botonActualEnProcesamientoActualizarProgreso(0,app.datosDeConfiguracion.waveLength_N);
                for k=1:app.datosDeConfiguracion.waveLength_N
                    datos = app.leerFicheroDatos(app.datosDeConfiguracion.raw_data_files{k});
                    %%% eliminacion de columna de canal de referencia
                    if (app.usoReferencia)
                        datos(:,(app.datosDeConfiguracion.detector_N+1):(app.datosDeConfiguracion.detector_N+1):end) = [];
                    end
                    app.datosDeSerie{k} = datos;
                    app.botonActualEnProcesamientoActualizarProgreso(k,app.datosDeConfiguracion.waveLength_N);
                    pause(0.1); %%% Refrescar UI botón
                end
                app.calcularHemoglobina();
                %%% elegimos la serie 1 fija para cálculo de linea de tiempo
                app.tiempoSegundosDatos = linspace(0,size(app.datosDeSerie{1},1)/app.datosDeConfiguracion.SamplingRate,size(app.datosDeSerie{1},1));
                app.numeroFuentes = app.datosDeConfiguracion.source_N;
                app.numeroDetectores = app.datosDeConfiguracion.detector_N;
                app.numeroCanales = app.datosDeConfiguracion.channel_N;
                app.frecuenciaMuestreo = app.datosDeConfiguracion.SamplingRate;
                
        end

        % Lectura de fichero de configuración
        function leerFicheroConfiguracion(app)
            app.datosDeConfiguracion=struct();
            app.registrarEvento(['Leyendo fichero ' app.nombreCompletoFicheroConfig '.']);
            fp =  fopen(app.nombreCompletoFicheroConfig,'r'); ...
            if fp<=0
                app.datosDeConfiguracion=-1;
                return;
            end
            app.datosDeConfiguracion.multi_site_N=1;
            app.datosDeConfiguracion.source_detector_key=[];
            while (1)
                sline = fgets(fp);
                if sline ==-1
                    break;
                end
                try
                    if contains(sline, 'raw_data_name')
                        app.datosDeConfiguracion.raw_data_name = eval(app.extraerValorConfiguracion(sline));
                    elseif contains(sline, 'SamplingRate')
                        app.datosDeConfiguracion.SamplingRate = eval(app.extraerValorConfiguracion(sline));
                    elseif contains(sline, 'waveLength_N')
                        app.datosDeConfiguracion.waveLength_N = eval(app.extraerValorConfiguracion(sline));
                    elseif contains(sline, 'source_N')
                        app.datosDeConfiguracion.source_N = eval(app.extraerValorConfiguracion(sline));
                    elseif contains(sline, 'detector_N')
                        app.datosDeConfiguracion.detector_N = eval(app.extraerValorConfiguracion(sline));
                    elseif contains(sline, 'time_point_N')
                        app.datosDeConfiguracion.time_point_N = eval(app.extraerValorConfiguracion(sline));
                    elseif contains(sline, 'source_detector_key') && isempty(app.datosDeConfiguracion.source_detector_key)
                        s1 = strrep(sline,';','];');
                        eval(s1);
                        sline = fgets(fp);
                        s2 =['tmp = [' sline ';'];
                        eval(s2);
                        if length(source_detector_key)~=length(tmp)
                            tmp1=[source_detector_key, zeros(1,length(tmp))];
                            tmp=[zeros(1,length(source_detector_key)),tmp]; %#ok<AGROW> 
                            source_detector_key=tmp1; 
                        end
                        app.datosDeConfiguracion.source_detector_key = [source_detector_key; tmp];
                    elseif contains(sline, 'multi_site_N')
                        app.datosDeConfiguracion.multi_site_N = eval(app.extraerValorConfiguracion(sline));
                        if isempty(app.datosDeConfiguracion.multi_site_N)
                            app.datosDeConfiguracion.multi_site_N=1;
                        end
                    elseif contains(sline, 'source_detector_key_site1')
                        s1 = strrep(sline,';','];');
                        eval(s1);
                        sline = fgets(fp);
                        s2 =['tmp = [' sline ';'];
                        eval(s2);
                        app.datosDeConfiguracion.source_detector_key_site1 = [source_detector_key_site1; tmp];
                    elseif contains(sline, 'source_detector_key_site2')
                        s1 = strrep(sline,';','];');
                        eval(s1);
                        sline = fgets(fp);
                        s2 =['tmp = [' sline ';'];
                        eval(s2);
                        app.datosDeConfiguracion.source_detector_key_site2 = [source_detector_key_site2; tmp];
                    elseif contains(sline, 'source_detector_key_site3')
                        s1 = strrep(sline,';','];');
                        eval(s1);
                        sline = fgets(fp);
                        s2 =['tmp = [' sline ';'];
                        eval(s2);
                        app.datosDeConfiguracion.source_detector_key_site3 = [source_detector_key_site3; tmp];
                    elseif contains(sline, 'source_detector_key_site4')
                        s1 = strrep(sline,';','];');
                        eval(s1);
                        sline = fgets(fp);
                        s2 =['tmp = [' sline ';'];
                        eval(s2);
                        app.datosDeConfiguracion.source_detector_key_site4 = [source_detector_key_site4; tmp];
                    end 
                catch ME
                    errordlg([ME.message , '(LeerFicheroConfiguracion)'])
                    break
                end
            end
            fclose(fp);
            % Completando campos de datosDeConfiguracion
            app.datosDeConfiguracion.raw_data_files = cell(app.datosDeConfiguracion.waveLength_N,1);
            for k=1:app.datosDeConfiguracion.waveLength_N
                app.datosDeConfiguracion.raw_data_files{k} = fullfile(app.rutaFicheroConfig, [app.datosDeConfiguracion.raw_data_name '.wl' num2str(k)]);  
            end
            app.datosDeConfiguracion.gain_setting_file = fullfile(app.rutaFicheroConfig, [app.datosDeConfiguracion.raw_data_name '.set']);
            app.datosDeConfiguracion.raw_data_files{3} = app.datosDeConfiguracion.gain_setting_file;
            app.datosDeConfiguracion.raw_data_files{4} = app.nombreCompletoFicheroConfig;
            %app.datosDeConfiguracion.channel_N = app.datosDeConfiguracion.source_N*(app.datosDeConfiguracion.detector_N+app.usoReferencia);
            app.datosDeConfiguracion.channel_N = app.datosDeConfiguracion.source_N*app.datosDeConfiguracion.detector_N;
            
            app.actualizarParametrosFiltros();

            app.registrarEvento(jsonencode(app.datosDeConfiguracion, "PrettyPrint", true));
        end
        
        function datos = leerFicheroDatos(app,nombreCompletoFicheroDatos)
            datos = load(nombreCompletoFicheroDatos);
            [rutaFicheroDatos,nombreFicheroDatos,extensionFicheroDatos] = fileparts(nombreCompletoFicheroDatos);
            app.rutaDirectorioDetectores = fullfile(rutaFicheroDatos,'Detectors');
            nombreFicheroDatosBinario = fullfile(app.rutaDirectorioDetectores,[nombreFicheroDatos 'b' extensionFicheroDatos]);
         
            if (~exist(app.rutaDirectorioDetectores,'dir'))
                mkdir(app.rutaDirectorioDetectores);
            end

            fp = fopen(nombreFicheroDatosBinario,'wb');
            fwrite(fp,app.datosDeConfiguracion.waveLength_N,'int16');
            fwrite(fp,app.datosDeConfiguracion.source_N,'int16');
            fwrite(fp,app.datosDeConfiguracion.detector_N,'int16');
            fwrite(fp,app.datosDeConfiguracion.channel_N,'int16');  %%% channel
            fwrite(fp,app.datosDeConfiguracion.time_point_N,'int16'); 
            fwrite(fp,datos,'float');
            fclose(fp);
        end
  
        function ocultarControlesPostSolicitudCalculoHemoglobina(app)
            set(app.LogoDYNOT4DEditField,'Visible','off');            
        end

        function prepararControlesPostCalculoHemoglobina(app) 
            try 
                ficheroGridLib = load(fullfile(app.rutaFicheroFEM, 'GRID.lib'), '-mat');
            catch ME
                errordlg([ME.message,'(prepararControlesPostCalculoImagenes)']);
                return;
            end
            info = ficheroGridLib.GRID_LIB;
            app.dimensionesVolumenListBox.Items = cellstr(num2str(info.DIMs));  
        end

        function prepararImagen(app,imagenAjustada)
            imagenAjustada(imagenAjustada == 0) = nan;
            switch app.vistaVolumenElegida %%% Convención neurológica (right is right) 
                case app.vistaVolumenAxialEtiqueta
                    app.volumenActualOrientado = permute(imagenAjustada,[1 2 3]);   
                    app.volumenActualOrientado = app.volumenActualOrientado (:,:,end:-1:1);                    
                case app.vistaVolumenSagitalEtiqueta
                    app.volumenActualOrientado = permute(imagenAjustada,[3 1 2]);
                    app.volumenActualOrientado = app.volumenActualOrientado (end:-1:1,:,:);                    
                case app.vistaVolumenCoronalEtiqueta
                    app.volumenActualOrientado = permute(imagenAjustada,[3 2 1]);
                    app.volumenActualOrientado = app.volumenActualOrientado (end:-1:1,:,:);                    
                otherwise % Axial
                    app.volumenActualOrientado = permute(imagenAjustada,[1 2 3]);   
                    app.volumenActualOrientado = app.volumenActualOrientado (:,:,end:-1:1);                    
            end            
        end

        function presentarImagenesRejilla(app,ax)
            %dimensionImagen = app.dimensionesVolumenImagen(1); % Depende de vista
            %raizCuadrada = sqrt(dimensionImagen);
            %pisoRaiz = floor(raizCuadrada);
            %if raizCuadrada ~= pisoRaiz 
            %    raizCuadrada = pisoRaiz + 1;
            %end
            %ladoDeRejillaX = raizCuadrada;
            %ladoDeRejillaY = round(dimensionImagen/ladoDeRejillaX);
            %axis(ax,'image');
            %ax.XGrid = 'on';
            %ax.YGrid = 'on';
            %xticks(ax,1:ladoDeRejillaX);
            %yticks(ax,1:ladoDeRejillaY);
            %ax.XLim = [0 ladoDeRejillaX];
            montaje = montage(app.volumenActualOrientado,'Parent',ax);
            %% Las siguientes dos líneas son necesarias porque montage (matlab2021b)
            %% levanta una "figure" a pesar de solicitar el montaje como variable de salida
            %% Hay que copiar primero CData de montaje y cerra la figura emergente.
            switch app.escalaColorElegida
                case app.escalaColorLinealEtiqueta
                   C = montaje.CData;
                case app.escalaColorSqrtEtiqueta
                   C = sqrt(montaje.CData);
                case app.escalaColorLogEtiqueta
                   C = log(montaje.CData);
                case app.escalaColorAbsLogEtiqueta
                   C = abs(log(montaje.CData));
                otherwise    
                   C = montaje.CData;
            end
            if (~app.figuraExterna)
                %close(gcf);
            end
            image(ax,C,'CDataMapping','scaled'); 
            axis(ax,'image');
            colormap(ax,jet(256));
            if (~isempty(C))
                NIC = C(~isinf(C));
                mm = min(NIC);
                MM = max(NIC);
                L =  (MM - mm) * 2/5;
                caxis(ax,[mm-L MM+L]);
                title(ax,[]);
                switch app.vistaVolumenElegida %%% Convención neurológica (right is right) 
                case app.vistaVolumenAxialEtiqueta
                    ylabel(ax,app.OrientacionIzquierdaEtiqueta);
                    xlabel(ax,app.OrientacionInferiorEtiqueta);
                case app.vistaVolumenSagitalEtiqueta
                    ylabel(ax,app.OrientacionAnteriorEtiqueta);
                    xlabel(ax,app.OrientacionInferiorEtiqueta);
                case app.vistaVolumenCoronalEtiqueta
                    ylabel(ax,app.OrientacionIzquierdaEtiqueta);
                    xlabel(ax,app.OrientacionInferiorEtiqueta);
                otherwise % Axial
                    ylabel(ax,[]);
                    xlabel(ax,[]);
                end  
                hYLabel = get(ax,'YLabel');
                set(hYLabel,'rotation',90,'VerticalAlignment','middle')
            end

            app.figuraExterna = false; % reset una vez desplegada la figura

        end
        
        function recalcularImagenes(app)
            app.botonActualEnProcesamiento(app.CalculoImagenesButton);
            app.dimensionesVolumenImagen = str2num(app.dimensionesVolumenListBox.Value); %#ok<ST2NM> 
            app.calcularParametrosFEM();
            app.calcularImagen();
            app.botonActualFinProcesamiento();
            app.visibilizarControlesPostCalculoImagenes();
            app.actualizarUIAxes();
        end

        function reconstruccionVolumen3D(app)            
            [f,p] = uigetfile({'*.nii'},'Fichero MNI','ch2.nii');
            if (f == 0) ; return; end
            app.procesamiento.MNIFileName = fullfile(p,f);

            [f,p] = uiputfile({'*.nii'},'Guardar volumen DOT 3D .nii ','vol_.nii'); 
            if (f == 0) ; return; end

            pathSalida = p;
            frames = [1 length(app.procesamiento.dotimg.tImg)];
            numeroCondiciones = length(app.procesamiento.CondNames);
            for cond = 1:numeroCondiciones
                fig=figure;
                DOTHUB_plotSurfaceDOTIMG(app.procesamiento.dotimg,app.procesamiento.rmap,frames,'condition',cond,'view',[-90 0]);
                savefig(fig,fullfile(pathSalida,sprintf('Condicion_%02d.fig',cond)));
                close(fig);
            end


            app.procesamiento.Vol3DFileName = fullfile(p,f);

            load(app.procesamiento.origMeshFileName,'-mat');  
            TRI = headVolumeMesh.elem(:,1:4);
            X = headVolumeMesh.node(:,1:3);
            MaxX = max(X);
            MinX = min(X);
            RES = [ 181 217 181]; %%% Resolución de volumen MNI utilizado en SPM
                                  %%% Orden de ejes ajustado para este procesamiento
            [xg,yg,zg] = ...
                meshgrid(   linspace(MinX(1),MaxX(1),RES(1)),...
                            linspace(MinX(2),MaxX(2),RES(2)),...
                            linspace(MinX(3),MaxX(3),RES(3)));
            XI = [xg(:),yg(:),zg(:)];
            [t,p] = tsearchn(X,TRI,XI);
            m1 = size(XI,1);
            v = find(~isnan(t(1:m1)));
            tt = t(v);
            pp = p(v,:);
            tri = TRI(tt,:);


            app.reconstruccionAlineadoGuardadoVolumen3D(tri,pp,v,m1,size(xg));

        end
    
        function reconstruccionAlineadoGuardadoVolumen3D(app,tri,pp,v,m1,dimensionesVolumen)
            %%% Falta código de elección de molécula, de bloque,
            %%% y de rango de imágenes dentro de un bloque que se 
            %%% quiere ver o promediar

            [p,f,e] = fileparts(app.procesamiento.Vol3DFileName);
        
            numeroCondiciones = size(app.volumenACalcular,3);
            for ixCondicion = 1:numeroCondiciones 
                for ixVolumen = 1:app.numeroVolumenesACalcular
                    I = squeeze(app.volumenACalcular(ixVolumen,:,ixCondicion));
                    I = I(:);
                    yy = I(tri);
                    zr = sum(pp.*yy,2);
                    zz = NaN*zeros(m1,1);
                    zz(v) = zr;
                    VOL3D = reshape(zz,dimensionesVolumen);
                    
                    %%% Alineación de la imagen espectroscópica contra el MNI de
                    %%% SPM
                    VOL3D = imrotate3(VOL3D,90,[0 0 1]);
                    %%% Escritura preliminar de volumen
                    fileName = fullfile(p,sprintf("%s%02d_%04d%s",f,ixCondicion,ixVolumen,e));
                    niftiwrite(VOL3D,fileName);
                    %%% Copia de transformación de MNI a VOL3D
                    infoMNInii = niftiinfo(app.procesamiento.MNIFileName);
                    infoVol3Dnii = niftiinfo(fileName);
                    infoVol3Dnii.Transform = infoMNInii.Transform;
                    infoVol3Dnii.TransformName = infoMNInii.TransformName;            
                    %%% Alineación (traslación). Se hizo luego de copia de transformación. Dejar así.   
                    posicionesNaN = isnan(VOL3D);
                    VOL3D(posicionesNaN) = 0;
                    VOL3D = imtranslate(VOL3D,[5 1 -5],'FillValues',0);
                    VOL3D(posicionesNaN) = NaN;
                    %%% Escritura final
                    niftiwrite(VOL3D,fileName,infoVol3Dnii);
                    app.botonActualEnProcesamientoActualizarProgreso(ixVolumen,app.numeroVolumenesACalcular);
                end
            end
        end

        function registrarEvento(app,linea)
            app.InformacionTextArea.Value{length(app.InformacionTextArea.Value)+1} = ...
                [ '[' datestr(datetime(now,'ConvertFrom','datenum')) '] ' linea]; 
            scroll(app.InformacionTextArea, 'bottom');
        end

        function muestras = segundosAMuestras(app,segundos)
            muestras = round(segundos * app.datosDeConfiguracion.SamplingRate);
            muestras (muestras > app.datosDeConfiguracion.time_point_N)= app.datosDeConfiguracion.time_point_N;
            muestras (muestras < 1) = 1;
            muestras = unique(muestras);
        end

        function verificarLimitesFiltro(app)             
             if (app.PasoAltoSlider.Visible  && app.PasoBajoSlider.Visible)
                 if (app.PasoBajoSlider.Value < app.PasoAltoSlider.Value)
                     app.PasoAltoSlider.Value = app.PasoBajoSlider.Value;
                 end
             end
        end
 
        function visibilizarControlesPostCargaDatos(app)
            %%%% 2023-11-22. Dependía de NAVI. En proceso de eliminación.
            %%% set(app.CargaFicherosFEMButton,'Visible','on');
            
            %%%% Código no dependiente de NAVI 2023-11-22
            app.UsaOptodoReferenciaCheckBox.Visible = 'on';
            app.CalculoValoresHemoglobinaButton.Visible = 'on';
            app.ConfiguracionAvanzadaTextArea.Enable = true;
            
        end
        
        function visibilizarControlesPostCargaFEM(app)
            app.UsaOptodoReferenciaCheckBox.Visible = 'on';
            app.CalculoValoresHemoglobinaButton.Visible = 'on';
            app.ConfiguracionAvanzadaTextArea.Enable = true;
        end

        function visibilizarControlesPostSolicitudCalculoHemoglobina(app)
            app.SeriesUIAxes.Visible = 'on';       
            app.VerFiguraSeparadaButton.Visible = 'on';
            app.VerFuentesDetectoresTree.Visible = 'on';
            app.TipoDeSerie.Visible = 'on';
            app.cambiarVisibilidadEditField(app.eventosEditField,app.EventosEditFieldLabel,'on');
            app.cambiarVisibilidadEditField(app.etiquetasEventosEditField,app.EtqEvLabel,'on');
            
        end

        function visibilizarControlesPostCalculoHemoglobina(app)
            app.CalculoImagenesButton.Visible = 'on';
            app.OpcionalFiltroDeDatosListBox.Visible = 'on'; 
            app.OpcionalFiltroLabel.Visible = 'on';
            %%% app.RemuestreoDeDatosCheckBox.Visible = 'on';
            app.volumenesIndividualesCheckBox.Visible = 'on';
            app.PreprocesadoButton.Visible = 'on';
            app.dimensionesVolumenListBox.Visible = 'on';
            app.dimensionDeVolumenLabel.Visible = 'on';
        end

        function visibilizarControlesPostCalculoImagenes(app)
            app.volumenDeImagenSlider.Visible = 'on';
            app.volumendeImagenLabel.Visible = 'on';
            app.volumenDeImagenEditField.Visible = 'on';
            app.seriesImagenesSwitch.Visible = 'on';
            app.seriesImagenesLabel.Visible = 'on';
            app.ComponenteImagenButtonGroup.Visible = 'on';
            app.ComponenteImagenLabel.Visible = 'on';
            app.vistaVolumenButtonGroup.Visible = 'on';
            app.vistaVolumenLabel.Visible = 'on';
            app.escalaColorButtonGroup.Visible = 'on';
            app.RestarMediaTemporalVolumenesCheckBox.Visible = 'on';
            app.TiempoSlider.Visible = 'on';
            app.TiempoEdit.Visible = 'on';
        end

        function visibilizarControlesPostPreprocesado(app)
            app.VisualizacionPreProcesadoButton.Visible = 'on';
        end

    end % methods

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargaDatosNIRXDYNOTButton
        function CargaDatosNIRXDYNOTButtonPushed(app, event)
            try
                app.datosDeSerie = [];
                [app.nombreFicheroConfig,app.rutaFicheroConfig] = uigetfile({'*_config.txt';'*.*'});
                app.nombreCompletoFicheroConfig = fullfile(app.rutaFicheroConfig,app.nombreFicheroConfig);
                if app.nombreFicheroConfig ~= 0
                    app.usoReferencia = get(app.UsaOptodoReferenciaCheckBox,'Value');
                    app.leerFicheroConfiguracion();
                    app.inicializarDataInfo();
                    app.visibilizarControlesPostCargaDatos();
                end
            catch ME
                errordlg([ME.message,'(CargaDatosNIRXDYNOTButtonPushed)'])
            end
        end

        % Button pushed function: CargaFicherosFEMButton
        function CargaFicherosFEMButtonPushed(app, event)
            try
             [app.nombreFicheroFEM,app.rutaFicheroFEM] = uigetfile({'coords.FEM';'*.*'},[],app.rutaFicheroConfig);
             if (app.nombreFicheroFEM == 0) ; return; end
              app.nombreCompletoFicheroFEM = fullfile(app.rutaFicheroFEM,app.nombreFicheroFEM);
              if app.nombreFicheroConfig ~= 0
                    %app.LeerFicheroFEM();
                    visibilizarControlesPostCargaFEM(app);
              end
            catch ME
                errgldg([ME.message,'(CargaFicherosFEMButtonPushed)'])
            end
        end

        % Button pushed function: CalculoValoresHemoglobinaButton
        function CalculoValoresHemoglobinaButtonPushed(app, event)
             cla(app.SeriesUIAxes);
             app.ocultarControlesPostSolicitudCalculoHemoglobina();
             app.visibilizarControlesPostSolicitudCalculoHemoglobina();
             app.botonActualEnProcesamiento(app.CalculoValoresHemoglobinaButton);
             %%% Lectura de datos WL
             app.lecturaDatos();
             app.botonActualFinProcesamiento();
             %%% Presentarlo para debugging
             app.actualizarUIAxes();
             %%% Creacion de fichero NAV (lo que llamaban filtrado o
             %%% f_*.NAV)
            
             %app.prepararControlesPostCalculoHemoglobina(); 
             app.visibilizarControlesPostCalculoHemoglobina();


        end

        % Button pushed function: VerFiguraSeparadaButton
        function VerFiguraSeparadaButtonPushed(app, event)
            app.actualizarFiguraSeparada();
        end

        % Selection changed function: TipoDeSerie
        function TipoDeSerieSelectionChanged(app, event)
            app.eleccionTipoSerie = eval(app.TipoDeSerie.SelectedObject.Tag);
            app.actualizarUIAxes();
        end

        % Callback function: VerFuentesDetectoresTree
        function VerFuentesDetectoresTreeCheckedNodesChanged(app, event)
            app.fuentesDetectoresHanCambiado = true;
            eleccion = app.UIAxesSeriesElegidaEtiqueta;
            app.seriesImagenesSwitch.Value = eleccion;
            app.ImagenesUIAxes.Visible = 'off';
            set(app.ImagenesUIAxes.Children,'Visible','off');
            app.SeriesUIAxes.Visible = 'on';
            set(app.SeriesUIAxes.Children,'Visible','on');
            app.actualizarUIAxes();
        end

        % Value changed function: OpcionalFiltroDeDatosListBox
        function OpcionalFiltroDeDatosListBoxValueChanged(app, event)
            app.actualizarFiltrado();
        end

        % Value changed function: PasoBajoSlider
        function PasoBajoSliderValueChanged(app, event)
            value = app.PasoBajoSlider.Value;
            app.CorteSuperiorEditField.Value = value; 
            app.actualizarFiltrado();
        end

        % Value changed function: PasoAltoSlider
        function PasoAltoSliderValueChanged(app, event)
            value = app.PasoAltoSlider.Value;
            app.corteInferiorEditField.Value = value; 
            app.actualizarFiltrado();
        end

        % Value changed function: corteInferiorEditField
        function corteInferiorEditFieldValueChanged(app, event)
            value = app.corteInferiorEditField.Value;
            app.PasoAltoSlider.Value = value;
            feval(get(app.PasoAltoSlider,'ValueChangedFcn'),app.PasoAltoSlider,[])
        end

        % Value changed function: CorteSuperiorEditField
        function CorteSuperiorEditFieldValueChanged(app, event)
            value = app.CorteSuperiorEditField.Value;
            app.PasoBajoSlider.Value = value;  
            feval(get(app.PasoBajoSlider,'ValueChangedFcn'),app.PasoBajoSlider,[])
        end

        % Button pushed function: limpiarRegistroEventosButton
        function limpiarRegistroEventosButtonPushed(app, event)
            app.InformacionTextArea.Value = {''};
        end

        % Button pushed function: CalculoImagenesButton
        function CalculoImagenesButtonPushed(app, event)
            app.recalcularImagenes();
        end

        % Value changed function: volumenDeImagenEditField
        function volumenDeImagenEditFieldValueChanged(app, event)
            app.volumenElegido = round(app.volumenDeImagenEditField.Value);
            app.volumenDeImagenEditField.Value = app.volumenElegido;
            app.actualizarUIAxes();
        end

        % Value changed function: volumenDeImagenSlider
        function volumenDeImagenSliderValueChanged(app, event)
            app.volumenElegido = round(app.volumenDeImagenSlider.Value);
            app.volumenDeImagenSlider.Value = app.volumenElegido;
            app.actualizarUIAxes();
        end

        % Value changed function: dimensionesVolumenListBox
        function dimensionesVolumenListBoxValueChanged(app, event)
            app.dimensionesVolumenImagen = str2num(app.dimensionesVolumenListBox.Value); %#ok<ST2NM> 
            app.actualizarUIAxes();
        end

        % Button down function: SeriesUIAxes
        function SeriesUIAxesButtonDown(app, event)
            
        end

        % Value changed function: seriesImagenesSwitch
        function seriesImagenesSwitchValueChanged(app, event)
            eleccion = app.seriesImagenesSwitch.Value;
            switch eleccion
                case app.UIAxesSeriesElegidaEtiqueta
                    app.SeriesUIAxes.Visible = 'on';
                    set(app.SeriesUIAxes.Children,'Visible','on');
                    app.ImagenesUIAxes.Visible = 'off';
                    set(app.ImagenesUIAxes.Children,'Visible','off');
                    app.actualizarSeries(app.SeriesUIAxes);
                case app.UIAxesImagenesElegidaEtiqueta
                    app.SeriesUIAxes.Visible = 'off';
                    set(app.SeriesUIAxes.Children,'Visible','off');
                    app.ImagenesUIAxes.Visible = 'on';
                    set(app.ImagenesUIAxes.Children,'Visible','on');
                    app.actualizarImagenes(app.ImagenesUIAxes);
            end
        end

        % Value changed function: eventosEditField
        function eventosEditFieldValueChanged(app, event)
            app.actualizarSeries(app.SeriesUIAxes);
        end

        % Selection changed function: ComponenteImagenButtonGroup
        function ComponenteImagenButtonGroupSelectionChanged(app, event)
            app.actualizarImagenes(app.ImagenesUIAxes);
        end

        % Selection changed function: vistaVolumenButtonGroup
        function vistaVolumenButtonGroupSelectionChanged(app, event)
            app.vistaVolumenElegida = app.vistaVolumenButtonGroup.SelectedObject.Tag;
            app.actualizarImagenes(app.ImagenesUIAxes);
        end

        % Selection changed function: escalaColorButtonGroup
        function escalaColorButtonGroupSelectionChanged(app, event)
            app.escalaColorElegida = app.escalaColorButtonGroup.SelectedObject.Tag;
            app.presentarImagenesRejilla(app.ImagenesUIAxes);
        end

        % Value changed function: TiempoSlider
        function TiempoSliderValueChanged(app, event)
            app.tiempoElegido = app.TiempoSlider.Value;
            app.TiempoEdit.Value = app.tiempoElegido;
            app.actualizarImagenes(app.ImagenesUIAxes);
        end

        % Value changed function: RestarMediaTemporalVolumenesCheckBox
        function RestarMediaTemporalVolumenesCheckBoxValueChanged(app, event)
            app.RestarMediaTemporalVolumenes = app.RestarMediaTemporalVolumenesCheckBox.Value;
        end

        % Value changed function: TiempoEdit
        function TiempoEditValueChanged(app, event)
            app.tiempoElegido = app.TiempoEdit.Value;
            app.TiempoSlider.Value = app.tiempoElegido;
            app.actualizarImagenes(app.ImagenesUIAxes);
        end

        % Value changed function: RemuestreoDeDatosCheckBox
        function RemuestreoDeDatosCheckBoxValueChanged(app, event)
            app.remuestrearDatos = app.RemuestreoDeDatosCheckBox.Value;
            app.actualizarSeries(app.SeriesUIAxes);
        end

        % Button pushed function: PreprocesadoButton
        function PreprocesadoButtonPushed(app, event)
            %%% Sólo para 2 longitudes de onda          
            
            app.registrarEvento('Preprocesado: Comienzo de preprocesamiento.');

            app.procesamiento.d1 = app.datosDeSerie{app.indiceSerieWL1};
            app.procesamiento.d1(:,app.canalesOcultos) = 0;
            app.procesamiento.d2 = app.datosDeSerie{app.indiceSerieWL2};
            app.procesamiento.d2(:,app.canalesOcultos) = 0;
            app.procesamiento.d = [app.procesamiento.d1 app.procesamiento.d2];

            %%%% El problema de valor cero en las series está en relación
            %%%% con el cálculo de la densidad óptica con la que homer2
            %%%% trabaja. Se opta por asignar un valor pequeño, lo más
            %%%% próximo a la resolución numérica de las medidas.
            %%%% Solo se arregla para el paso de preprocesamiento y
            %%%% obtención de volúmenes DOT. Hay que hacer lo mismo
            %%%% para el cálculo de OD que se hace en la interfaz gráfica,
            %%%% 
            app.procesamiento.d(app.procesamiento.d == 0) = app.epsilonParaSeries;
            
            app.procesamiento.t = app.tiempoSegundosDatos(:); %%% tiempo debe ser vector columna
            
            % Momento de las condiciones
            app.procesamiento.CondNames = eval(app.etiquetasEventosEditField.Value);      %%% No hay nombre de eventos
            
            numeroCondiciones = length(app.procesamiento.CondNames);
            app.procesamiento.s = zeros(length(app.procesamiento.t),numeroCondiciones);
            
            for ix = 1:numeroCondiciones
                app.procesamiento.s(app.eventosMuestras{ix},ix) = 1;
            end
            
            app.procesamiento.cfgFileName = uigetfile({'*.cfg'},'Fichero de pasos de procesamiento (homer2)','Pipeline.cfg');
            if (app.procesamiento.cfgFileName == 0) ; return; end
            app.procesamiento.origMeshFileName = uigetfile({'*.mshs'},'Fichero de rejilla de elementos finitos','AdultMNI152.mshs'); 
            if (app.procesamiento.origMeshFileName == 0) ; return; end
            app.procesamiento.estructuraFileName = uigetfile({'*.SD3D'},'Fichero de posición de optodos','Posiciones22Optodos.SD3D');
            if (app.procesamiento.estructuraFileName == 0) ; return; end
            app.procesamiento.estructura = load (app.procesamiento.estructuraFileName,'SD3D','-mat');
            app.procesamiento.SD = app.procesamiento.estructura.SD3D;
            app.procesamiento.SD3D = app.procesamiento.SD;
            app.procesamiento.aux = zeros(length(app.procesamiento.t),8);
            
            %%%% Creando fichero de formato .nirs
            %%%% Para uso de mhs


            [f,p] = uiputfile({'*.nirs'},'Generar fichero .nirs','PosicionesOptodos.nirs');
            if (f == 0) ; return; end
            app.procesamiento.nirsFileName = fullfile(p,f);
            nirs = struct();
            nirs.SD = app.procesamiento.SD;
            nirs.SD3D = app.procesamiento.SD3D;
            nirs.aux = app.procesamiento.aux;
            nirs.d = app.procesamiento.d;
            nirs.s = app.procesamiento.s;
            nirs.t = app.procesamiento.t;
            nirs.CondNames = app.procesamiento.CondNames;

            %%% Los índices de canales mostrados se amplían para indexar
            %%% adecuadamente las columnas de la matriz correspondientes a 
            %%% la otra longitud de onda.
            nirs.canalesMostrados = [app.canalesMostrados (app.canalesMostrados(end) + app.canalesMostrados)];
            nirs.pasoAltoFrecuenciaCorte = app.pasoAltoFrecuenciaCorte;
            nirs.pasoBajoFrecuenciaCorte = app.pasoBajoFrecuenciaCorte;
            nirs.SamplingRate = app.datosDeConfiguracion.SamplingRate;
            nirs.time_point_N = app.datosDeConfiguracion.time_point_N;
            nirs.epsilonParaFiltro = app.epsilonParaFiltro;
            nirs.segundosDeRellenoParaFiltros = app.segundosDeRellenoParaFiltros; 
            nirs.filtro = app.OpcionalFiltroDeDatosListBox.Value;
            
            save(app.procesamiento.nirsFileName,'-struct','nirs','-v7.3');
            
            app.procesamiento.numeroCanalesOriginal = app.numeroCanales;


            %%%% Plot de modelo de rejilla MNI
            %%%% Si no se quitan los colocalizados --> Nans y error en
            %%%% generación (imágenes vacías) por rho == 0 en
            %%%% denominador del hmrOD2Conc. 
            [prepro, preproFileName] = DOTHUB_runHomerPrepro(app.procesamiento.nirsFileName,app.procesamiento.cfgFileName);
            [rmap, rmapFileName] = DOTHUB_meshRegistration(app.procesamiento.nirsFileName,app.procesamiento.origMeshFileName);
            app.procesamiento.rmap = rmap;
            app.procesamiento.prepro = prepro;
            app.procesamiento.preproFileName = preproFileName;
            app.procesamiento.rmapFileName = rmapFileName;
            DOTHUB_plotRMAP(app.procesamiento.rmap);
            
            %%% Cálculo de Jacobiana
            basis = [30 30 30];
            [jac, jacFileName] = DOTHUB_makeToastJacobian(app.procesamiento.rmapFileName,basis);
            app.procesamiento.jac = jac;
            app.procesamiento.jacFileName = jacFileName;
            
            %%% Inversión de Jacobiana
            [invjac, invjacFileName] = DOTHUB_invertJacobian(...
            app.procesamiento.jacFileName,app.procesamiento.preproFileName,'saveFlag',true,...
            'reconMethod','multispectral','hyperParameter',0.01);
            app.procesamiento.invjac = invjac;
            app.procesamiento.invjacFileName = invjacFileName;
            
            %%% Reconstrucción de imagen
            %%% FM MODIFICACION
            fullVols = app.volumenesIndividualesCheckBox.Value;
            [dotimg, dotimgFileName] = DOTHUB_reconstruction(...
            app.procesamiento.preproFileName,[],app.procesamiento.invjacFileName,app.procesamiento.rmapFileName,...
            'saveVolumeImages',true,'fullVols',fullVols);
            %%%%%%%%%%%%%%%%%
            %%%[dotimg, dotimgFileName] = DOTHUB_reconstruction(...
            %%%    app.procesamiento.preproFileName,[],app.procesamiento.invjacFileName,app.procesamiento.rmapFileName,...
            %%%    'saveVolumeImages',true);
            app.procesamiento.dotimg = dotimg;
            app.procesamiento.dotimgFileName = dotimgFileName;
            
            app.visibilizarControlesPostPreprocesado();        

            app.registrarEvento('Preprocesado: Fin de preprocesamiento.');

        end

        % Button pushed function: VisualizacionPreProcesadoButton
        function VisualizacionPreProcesadoButtonPushed(app, event)
            %%% Despliegue visual
            %timeRange = [10 15]; %seconds post-onset
            %fs = length(app.procesamiento.dotimg.tImg)./DOTHUB_range(app.procesamiento.dotimg.tImg);
            %frameRange = round((timeRange + abs(min(app.procesamiento.dotimg.tImg))).*fs);
            %frames = frameRange(1):frameRange(2);
            
            app.registrarEvento('Visualización: Ejecutando VisualizacionPreProcesado.');

            %%% Selección en UI 
            switch app.eleccionTipoSerie
                case app.indiceSerieHbO  
                    app.volumenACalcular = app.procesamiento.dotimg.hbo.vol;
                    app.registrarEvento('Visualización: elegida tipo de serie HbO.');
                case app.indiceSerieHbR  
                    app.volumenACalcular = app.procesamiento.dotimg.hbr.vol;
                    app.registrarEvento('Visualización: elegida tipo de serie HbR.');
                otherwise
                    app.registrarEvento('Visualización: ELEGIR HbO, HbR o HbTot en menú de Datos Visualizados.');
                    return;
            end
            
            app.numeroVolumenesACalcular = size(app.volumenACalcular,1);
              

            app.botonActualEnProcesamiento(app.VisualizacionPreProcesadoButton);
            app.botonActualEnProcesamientoActualizarProgreso(0,app.numeroVolumenesACalcular);
            

            %%%% DOTHUB_plotVolumeDOTIMG(app.procesamiento.dotimg,app.procesamiento.rmap,frames,'slicePos',[-12 40 48]);
            
            %DOTHUB_movieSurfaceDOTIMG(dotimg,rmap,'condition2movie.mp4','condition',2,'shading','interp')


            %%%% Reconstrucción tridimensional. 
            app.reconstruccionVolumen3D();
            app.botonActualFinProcesamiento();

            app.registrarEvento('Visualización: Fin de VisualizacionPreProcesado.');

            %procesamiento = app.procesamiento;  
            %save('EjemploProcesamiento.mat','-struct','procesamiento','-v7.3')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {619, 619};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {181, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CargaDatosNIRXDYNOTButton = uibutton(app.LeftPanel, 'push');
            app.CargaDatosNIRXDYNOTButton.ButtonPushedFcn = createCallbackFcn(app, @CargaDatosNIRXDYNOTButtonPushed, true);
            app.CargaDatosNIRXDYNOTButton.Text = 'Carga de datos NIRX - DYNOT';
            app.OpcionalFiltroDeDatosListBox = uilistbox(app.LeftPanel);
            app.OpcionalFiltroDeDatosListBox.Items = {'Ninguno', 'Paso bajo', 'Paso alto + media', 'Paso banda', 'DRIFTER', 'Wavelet'};
            app.OpcionalFiltroDeDatosListBox.ValueChangedFcn = createCallbackFcn(app, @OpcionalFiltroDeDatosListBoxValueChanged, true);
            app.OpcionalFiltroDeDatosListBox.Visible = 'off';
            app.OpcionalFiltroDeDatosListBox.Value = 'Ninguno';
            app.CalculoValoresHemoglobinaButton = uibutton(app.LeftPanel, 'push');
            app.CalculoValoresHemoglobinaButton.ButtonPushedFcn = createCallbackFcn(app, @CalculoValoresHemoglobinaButtonPushed, true);
            app.CalculoValoresHemoglobinaButton.Visible = 'off';
            app.CalculoValoresHemoglobinaButton.Text = 'Cálculo de valores de hemoglobina';
            app.UsaOptodoReferenciaCheckBox = uicheckbox(app.LeftPanel);
            app.UsaOptodoReferenciaCheckBox.Text = 'Usa optodo de referencia';
            app.UsaOptodoReferenciaCheckBox.Value = true;
            app.CargaFicherosFEMButton = uibutton(app.LeftPanel, 'push');
            app.CargaFicherosFEMButton.ButtonPushedFcn = createCallbackFcn(app, @CargaFicherosFEMButtonPushed, true);
            app.CargaFicherosFEMButton.Visible = 'off';
            app.CargaFicherosFEMButton.Text = 'Carga de modelo de región con definición de optodos (FEM)';
            app.ConfiguracionAvanzadaTextArea = uitextarea(app.LeftPanel);
            app.ConfiguracionAvanzadaTextArea.Enable = 'off';
            app.ConfiguracionAvanzadaTextArea.Value = {'%'; '%Configuración avanzada'; '%'; 'CV_umbral = 25;'; 'CV_rango_base = [0 16];  %% sg'; 'Sin_canales_malos = 0;'; 'Procesar_rango =  [1 end]; %% sg '; 'Valor_corte_SV = 98;'};
            app.OpcionalFiltroLabel = uilabel(app.LeftPanel);
            app.OpcionalFiltroLabel.Visible = 'off';
            app.OpcionalFiltroLabel.Text = 'Opcional: Filtro de datos';
            app.CalculoImagenesButton = uibutton(app.LeftPanel, 'push');
            app.CalculoImagenesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculoImagenesButtonPushed, true);
            app.CalculoImagenesButton.Visible = 'off';
            app.CalculoImagenesButton.Text = 'Cálculo de imágenes ';
            app.dimensionesVolumenListBox = uilistbox(app.LeftPanel);
            app.dimensionesVolumenListBox.ValueChangedFcn = createCallbackFcn(app, @dimensionesVolumenListBoxValueChanged, true);
            app.dimensionesVolumenListBox.Visible = 'off';
            app.dimensionDeVolumenLabel = uilabel(app.LeftPanel);
            app.dimensionDeVolumenLabel.Visible = 'off';
            app.dimensionDeVolumenLabel.Text = 'Dimensión de volumen';
            app.RemuestreoDeDatosCheckBox = uicheckbox(app.LeftPanel);
            app.RemuestreoDeDatosCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemuestreoDeDatosCheckBoxValueChanged, true);
            app.RemuestreoDeDatosCheckBox.Visible = 'off';
            app.RemuestreoDeDatosCheckBox.Text = 'Remuestreo de datos 1Hz';
            app.PreprocesadoButton = uibutton(app.LeftPanel, 'push');
            app.PreprocesadoButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocesadoButtonPushed, true);
            app.PreprocesadoButton.Visible = 'off';
            app.PreprocesadoButton.Text = 'Pre-procesado';
            app.volumenesIndividualesCheckBox = uicheckbox(app.LeftPanel);
            app.volumenesIndividualesCheckBox.Visible = 'off';
            app.volumenesIndividualesCheckBox.Text = 'Generar vols. individuales';
            app.VisualizacionPreProcesadoButton = uibutton(app.LeftPanel, 'push');
            app.VisualizacionPreProcesadoButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizacionPreProcesadoButtonPushed, true);
            app.VisualizacionPreProcesadoButton.Visible = 'off';
            app.VisualizacionPreProcesadoButton.Text = 'Visualización';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.SeriesUIAxes = uiaxes(app.GridLayout2);
            title(app.SeriesUIAxes, 'Canales ópticos')
            xlabel(app.SeriesUIAxes, 'sg')
            ylabel(app.SeriesUIAxes, 'U.A.')
            app.SeriesUIAxes.ButtonDownFcn = createCallbackFcn(app, @SeriesUIAxesButtonDown, true);
            app.SeriesUIAxes.Visible = 'off';
            app.ImagenesUIAxes = uiaxes(app.GridLayout2);
            xlabel(app.ImagenesUIAxes, 'X')
            ylabel(app.ImagenesUIAxes, 'Y')
            zlabel(app.ImagenesUIAxes, 'Z')
            app.ImagenesUIAxes.Visible = 'off';
            app.VerFiguraSeparadaButton = uibutton(app.GridLayout2, 'push');
            app.VerFiguraSeparadaButton.ButtonPushedFcn = createCallbackFcn(app, @VerFiguraSeparadaButtonPushed, true);
            app.VerFiguraSeparadaButton.Visible = 'off';
            app.VerFiguraSeparadaButton.Text = 'Ver en figura separada';
            app.LogoDYNOT4DEditField = uieditfield(app.GridLayout2, 'text');
            app.LogoDYNOT4DEditField.Editable = 'off';
            app.LogoDYNOT4DEditField.Enable = 'off';
            app.LogoDYNOT4DEditField.Value = 'DYNOT4D';
            app.InformacionTextArea = uitextarea(app.GridLayout2);
            app.InformacionTextArea.Editable = 'off';
            app.InformacionTextArea.Value = {'%'; '% Información de procesamiento'; '%'};
            app.PasoAltoSlider = uislider(app.GridLayout2);
            app.PasoAltoSlider.ValueChangedFcn = createCallbackFcn(app, @PasoAltoSliderValueChanged, true);
            app.PasoAltoSlider.Visible = 'off';
            app.VerFuentesDetectoresTree = uitree(app.GridLayout2, 'checkbox');
            app.VerFuentesDetectoresTree.Visible = 'off';
            app.VerFuentesCheckbox = uitreenode(app.VerFuentesDetectoresTree);
            app.VerFuentesCheckbox.Text = 'Fuentes';
            app.VerFuente01Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente01Checkbox.Text = '1';
            app.VerFuente02Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente02Checkbox.Text = '2';
            app.VerFuente03Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente03Checkbox.Text = '3';
            app.VerFuente04Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente04Checkbox.Text = '4';
            app.VerFuente05Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente05Checkbox.Text = '5';
            app.VerFuente06Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente06Checkbox.Text = '6';
            app.VerFuente07Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente07Checkbox.Text = '7';
            app.VerFuente08Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente08Checkbox.Text = '8';
            app.VerFuente09Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente09Checkbox.Text = '9';
            app.VerFuente10Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente10Checkbox.Text = '10';
            app.VerFuente11Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente11Checkbox.Text = '11';
            app.VerFuente12Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente12Checkbox.Text = '12';
            app.VerFuente13Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente13Checkbox.Text = '13';
            app.VerFuente14Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente14Checkbox.Text = '14';
            app.VerFuente15Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente15Checkbox.Text = '15';
            app.VerFuente16Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente16Checkbox.Text = '16';
            app.VerFuente17Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente17Checkbox.Text = '17';
            app.VerFuente18Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente18Checkbox.Text = '18';
            app.VerFuente19Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente19Checkbox.Text = '19';
            app.VerFuente20Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente20Checkbox.Text = '20';
            app.VerFuente21Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente21Checkbox.Text = '21';
            app.VerFuente22Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente22Checkbox.Text = '22';
            app.VerFuente23Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente23Checkbox.Text = '23';
            app.VerFuente24Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente24Checkbox.Text = '24';
            app.VerFuente25Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente25Checkbox.Text = '25';
            app.VerFuente26Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente26Checkbox.Text = '26';
            app.VerFuente27Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente27Checkbox.Text = '27';
            app.VerFuente28Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente28Checkbox.Text = '28';
            app.VerFuente29Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente29Checkbox.Text = '29';
            app.VerFuente30Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente30Checkbox.Text = '30';
            app.VerFuente31Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente31Checkbox.Text = '31';
            app.VerFuente32Checkbox = uitreenode(app.VerFuentesCheckbox);
            app.VerFuente32Checkbox.Text = '32';
            app.VerDetectoresCheckbox = uitreenode(app.VerFuentesDetectoresTree);
            app.VerDetectoresCheckbox.Text = 'Detectores';
            app.VerDetector01Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector01Checkbox.Text = '1';
            app.VerDetector02Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector02Checkbox.Text = '2';
            app.VerDetector03Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector03Checkbox.Text = '3';
            app.VerDetector04Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector04Checkbox.Text = '4';
            app.VerDetector05Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector05Checkbox.Text = '5';
            app.VerDetector06Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector06Checkbox.Text = '6';
            app.VerDetector07Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector07Checkbox.Text = '7';
            app.VerDetector08Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector08Checkbox.Text = '8';
            app.VerDetector09Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector09Checkbox.Text = '9';
            app.VerDetector10Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector10Checkbox.Text = '10';
            app.VerDetector11Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector11Checkbox.Text = '11';
            app.VerDetector12Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector12Checkbox.Text = '12';
            app.VerDetector13Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector13Checkbox.Text = '13';
            app.VerDetector14Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector14Checkbox.Text = '14';
            app.VerDetector15Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector15Checkbox.Text = '15';
            app.VerDetector16Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector16Checkbox.Text = '16';
            app.VerDetector17Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector17Checkbox.Text = '17';
            app.VerDetector18Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector18Checkbox.Text = '18';
            app.VerDetector19Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector19Checkbox.Text = '19';
            app.VerDetector20Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector20Checkbox.Text = '20';
            app.VerDetector21Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector21Checkbox.Text = '21';
            app.VerDetector22Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector22Checkbox.Text = '22';
            app.VerDetector23Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector23Checkbox.Text = '23';
            app.VerDetector24Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector24Checkbox.Text = '24';
            app.VerDetector25Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector25Checkbox.Text = '25';
            app.VerDetector26Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector26Checkbox.Text = '26';
            app.VerDetector27Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector27Checkbox.Text = '27';
            app.VerDetector28Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector28Checkbox.Text = '28';
            app.VerDetector29Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector29Checkbox.Text = '29';
            app.VerDetector30Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector30Checkbox.Text = '30';
            app.VerDetector31Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector31Checkbox.Text = '31';
            app.VerDetector32Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector32Checkbox.Text = '32';
            app.VerDetector33Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector33Checkbox.Text = '33';
            app.VerDetector34Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector34Checkbox.Text = '34';
            app.VerDetector35Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector35Checkbox.Text = '35';
            app.VerDetector36Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector36Checkbox.Text = '36';
            app.VerDetector37Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector37Checkbox.Text = '37';
            app.VerDetector38Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector38Checkbox.Text = '38';
            app.VerDetector39Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector39Checkbox.Text = '39';
            app.VerDetector40Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector40Checkbox.Text = '40';
            app.VerDetector41Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector41Checkbox.Text = '41';
            app.VerDetector42Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector42Checkbox.Text = '42';
            app.VerDetector43Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector43Checkbox.Text = '43';
            app.VerDetector44Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector44Checkbox.Text = '44';
            app.VerDetector45Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector45Checkbox.Text = '45';
            app.VerDetector46Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector46Checkbox.Text = '46';
            app.VerDetector47Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector47Checkbox.Text = '47';
            app.VerDetector48Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector48Checkbox.Text = '48';
            app.VerDetector49Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector49Checkbox.Text = '49';
            app.VerDetector50Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector50Checkbox.Text = '50';
            app.VerDetector51Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector51Checkbox.Text = '51';
            app.VerDetector52Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector52Checkbox.Text = '52';
            app.VerDetector53Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector53Checkbox.Text = '53';
            app.VerDetector54Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector54Checkbox.Text = '54';
            app.VerDetector55Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector55Checkbox.Text = '55';
            app.VerDetector56Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector56Checkbox.Text = '56';
            app.VerDetector57Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector57Checkbox.Text = '57';
            app.VerDetector58Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector58Checkbox.Text = '58';
            app.VerDetector59Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector59Checkbox.Text = '59';
            app.VerDetector60Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector60Checkbox.Text = '60';
            app.VerDetector61Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector61Checkbox.Text = '61';
            app.VerDetector62Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector62Checkbox.Text = '62';
            app.VerDetector63Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector63Checkbox.Text = '63';
            app.VerDetector64Checkbox = uitreenode(app.VerDetectoresCheckbox);
            app.VerDetector64Checkbox.Text = '64';
            % Assign Checked Nodes
            app.VerFuentesDetectoresTree.CheckedNodes = [app.VerFuente01Checkbox, app.VerFuente02Checkbox, app.VerFuente03Checkbox, app.VerFuente04Checkbox, app.VerFuente05Checkbox, app.VerFuente06Checkbox, app.VerFuente07Checkbox, app.VerFuente08Checkbox, app.VerFuente09Checkbox, app.VerFuente10Checkbox, app.VerFuente11Checkbox, app.VerFuente12Checkbox, app.VerFuente13Checkbox, app.VerFuente14Checkbox, app.VerFuente15Checkbox, app.VerFuente16Checkbox, app.VerFuente17Checkbox, app.VerFuente18Checkbox, app.VerFuente19Checkbox, app.VerFuente20Checkbox, app.VerFuente21Checkbox, app.VerFuente22Checkbox, app.VerFuente23Checkbox, app.VerFuente24Checkbox, app.VerFuente25Checkbox, app.VerFuente26Checkbox, app.VerFuente27Checkbox, app.VerFuente28Checkbox, app.VerFuente29Checkbox, app.VerFuente30Checkbox, app.VerFuente31Checkbox, app.VerFuente32Checkbox, app.VerDetector01Checkbox, app.VerDetector02Checkbox, app.VerDetector03Checkbox, app.VerDetector04Checkbox, app.VerDetector05Checkbox, app.VerDetector06Checkbox, app.VerDetector07Checkbox, app.VerDetector08Checkbox, app.VerDetector09Checkbox, app.VerDetector10Checkbox, app.VerDetector11Checkbox, app.VerDetector12Checkbox, app.VerDetector13Checkbox, app.VerDetector14Checkbox, app.VerDetector15Checkbox, app.VerDetector16Checkbox, app.VerDetector17Checkbox, app.VerDetector18Checkbox, app.VerDetector19Checkbox, app.VerDetector20Checkbox, app.VerDetector21Checkbox, app.VerDetector22Checkbox, app.VerDetector23Checkbox, app.VerDetector24Checkbox, app.VerDetector25Checkbox, app.VerDetector26Checkbox, app.VerDetector27Checkbox, app.VerDetector28Checkbox, app.VerDetector29Checkbox, app.VerDetector30Checkbox, app.VerDetector31Checkbox, app.VerDetector32Checkbox, app.VerDetector33Checkbox, app.VerDetector34Checkbox, app.VerDetector35Checkbox, app.VerDetector36Checkbox, app.VerDetector37Checkbox, app.VerDetector38Checkbox, app.VerDetector39Checkbox, app.VerDetector40Checkbox, app.VerDetector41Checkbox, app.VerDetector42Checkbox, app.VerDetector43Checkbox, app.VerDetector44Checkbox, app.VerDetector45Checkbox, app.VerDetector46Checkbox, app.VerDetector47Checkbox, app.VerDetector48Checkbox, app.VerDetector49Checkbox, app.VerDetector50Checkbox, app.VerDetector51Checkbox, app.VerDetector52Checkbox, app.VerDetector53Checkbox, app.VerDetector54Checkbox, app.VerDetector55Checkbox, app.VerDetector56Checkbox, app.VerDetector57Checkbox, app.VerDetector58Checkbox, app.VerDetector59Checkbox, app.VerDetector60Checkbox, app.VerDetector61Checkbox, app.VerDetector62Checkbox, app.VerDetector63Checkbox, app.VerDetector64Checkbox, app.VerFuentesCheckbox, app.VerDetectoresCheckbox];
            % Assign Checked Nodes
            app.VerFuentesDetectoresTree.CheckedNodesChangedFcn = createCallbackFcn(app, @VerFuentesDetectoresTreeCheckedNodesChanged, true);
            app.CorteSuperiorEditField = uieditfield(app.GridLayout2, 'numeric');
            app.CorteSuperiorEditField.ValueChangedFcn = createCallbackFcn(app, @CorteSuperiorEditFieldValueChanged, true);
            app.CorteSuperiorEditField.Visible = 'off';
            app.CortesuperiorHzEditFieldLabel = uilabel(app.GridLayout2);
            app.CortesuperiorHzEditFieldLabel.Visible = 'off';
            app.CortesuperiorHzEditFieldLabel.Text = 'Corte superior (Hz)';
            app.PasoBajoSlider = uislider(app.GridLayout2);
            app.PasoBajoSlider.ValueChangedFcn = createCallbackFcn(app, @PasoBajoSliderValueChanged, true);
            app.PasoBajoSlider.Visible = 'off';
            app.PasoBajoSlider.Value = 73.3974052117791;
            app.TipoDeSerie = uibuttongroup(app.GridLayout2);
            app.TipoDeSerie.SelectionChangedFcn = createCallbackFcn(app, @TipoDeSerieSelectionChanged, true);
            app.TipoDeSerie.Title = 'Datos visualizados';
            app.TipoDeSerie.Visible = 'off';
            app.longitudOnda760RadioButton = uiradiobutton(app.TipoDeSerie);
            app.longitudOnda760RadioButton.Tag = '1';
            app.longitudOnda760RadioButton.Text = '760';
            app.longitudOnda760RadioButton.Value = true;
            app.longitudOnda830RadioButton = uiradiobutton(app.TipoDeSerie);
            app.longitudOnda830RadioButton.Tag = '2';
            app.longitudOnda830RadioButton.Text = '830';
            app.HbORadioButton = uiradiobutton(app.TipoDeSerie);
            app.HbORadioButton.Tag = '3';
            app.HbORadioButton.Text = 'HbO';
            app.HbRRadioButton = uiradiobutton(app.TipoDeSerie);
            app.HbRRadioButton.Tag = '4';
            app.HbRRadioButton.Text = 'HbR';
            app.HbTotRadioButton = uiradiobutton(app.TipoDeSerie);
            app.HbTotRadioButton.Tag = '5';
            app.HbTotRadioButton.Text = 'HbTot';
            app.HbSatRadioButton = uiradiobutton(app.TipoDeSerie);
            app.HbSatRadioButton.Tag = '6';
            app.HbSatRadioButton.Text = 'HbSat';
            app.HbSatLogRadioButton = uiradiobutton(app.TipoDeSerie);
            app.HbSatLogRadioButton.Tag = '7';
            app.HbSatLogRadioButton.Text = 'HbSatLog';
            app.OD760RadioButton = uiradiobutton(app.TipoDeSerie);
            app.OD760RadioButton.Tag = '8';
            app.OD760RadioButton.Text = 'OD (760)';
            app.OD830RadioButton = uiradiobutton(app.TipoDeSerie);
            app.OD830RadioButton.Tag = '9';
            app.OD830RadioButton.Text = 'OD (830)';
            app.CorteinferiorHzEditFieldLabel = uilabel(app.GridLayout2);
            app.CorteinferiorHzEditFieldLabel.Visible = 'off';
            app.CorteinferiorHzEditFieldLabel.Text = 'Corte inferior (Hz)';
            app.corteInferiorEditField = uieditfield(app.GridLayout2, 'numeric');
            app.corteInferiorEditField.ValueChangedFcn = createCallbackFcn(app, @corteInferiorEditFieldValueChanged, true);
            app.corteInferiorEditField.Visible = 'off';
            app.limpiarRegistroEventosButton = uibutton(app.GridLayout2, 'push');
            app.limpiarRegistroEventosButton.ButtonPushedFcn = createCallbackFcn(app, @limpiarRegistroEventosButtonPushed, true);
            app.limpiarRegistroEventosButton.Text = 'Limpiar registro de eventos';
            app.EventosEditFieldLabel = uilabel(app.GridLayout2);
            app.EventosEditFieldLabel.Visible = 'off';
            app.EventosEditFieldLabel.Text = 'Eventos';
            app.eventosEditField = uieditfield(app.GridLayout2, 'text');
            app.eventosEditField.ValueChangedFcn = createCallbackFcn(app, @eventosEditFieldValueChanged, true);
            app.eventosEditField.Visible = 'off';
            app.eventosEditField.Value = '{[21.05],[48.6],[69],[85.6],[99.6],[111.2],[126.4],[141.4],[156.4],[167]}';
            app.volumendeImagenLabel = uilabel(app.GridLayout2);
            app.volumendeImagenLabel.Visible = 'off';
            app.volumendeImagenLabel.Text = 'Volumen de Imagen ';
            app.volumenDeImagenSlider = uislider(app.GridLayout2);
            app.volumenDeImagenSlider.ValueChangedFcn = createCallbackFcn(app, @volumenDeImagenSliderValueChanged, true);
            app.volumenDeImagenSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.volumenDeImagenSlider.Visible = 'off';
            app.volumenDeImagenEditField = uieditfield(app.GridLayout2, 'numeric');
            app.volumenDeImagenEditField.ValueChangedFcn = createCallbackFcn(app, @volumenDeImagenEditFieldValueChanged, true);
            app.volumenDeImagenEditField.Visible = 'off';
            app.seriesImagenesLabel = uilabel(app.GridLayout2);
            app.seriesImagenesLabel.Visible = 'off';
            app.seriesImagenesLabel.Text = 'Series / Imágenes  ';
            app.seriesImagenesSwitch = uiswitch(app.GridLayout2, 'slider');
            app.seriesImagenesSwitch.Items = {'Series', 'Imágenes'};
            app.seriesImagenesSwitch.ValueChangedFcn = createCallbackFcn(app, @seriesImagenesSwitchValueChanged, true);
            app.seriesImagenesSwitch.Visible = 'off';
            app.seriesImagenesSwitch.Value = 'Series';
            app.enProcesamientoButton = uibutton(app.GridLayout2, 'push');
            app.enProcesamientoButton.Visible = 'off';
            app.enProcesamientoButton.Text = '';
            app.ComponenteImagenButtonGroup = uibuttongroup(app.GridLayout2);
            app.ComponenteImagenButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ComponenteImagenButtonGroupSelectionChanged, true);
            app.ComponenteImagenButtonGroup.Visible = 'off';
            app.MagnitudButton = uiradiobutton(app.ComponenteImagenButtonGroup);
            app.MagnitudButton.Tag = 'Magnitud';
            app.MagnitudButton.Text = 'Magnitud';
            app.MagnitudButton.Value = true;
            app.RealButton = uiradiobutton(app.ComponenteImagenButtonGroup);
            app.RealButton.Tag = 'Real';
            app.RealButton.Text = 'Real';
            app.ImagButton = uiradiobutton(app.ComponenteImagenButtonGroup);
            app.ImagButton.Tag = 'Imaginaria';
            app.ImagButton.Text = 'Imag';
            app.FaseButton = uiradiobutton(app.ComponenteImagenButtonGroup);
            app.FaseButton.Tag = 'Fase';
            app.FaseButton.Text = 'Fase';
            app.ComponenteImagenLabel = uilabel(app.GridLayout2);
            app.ComponenteImagenLabel.Visible = 'off';
            app.ComponenteImagenLabel.Text = 'Componente Imagen';
            app.vistaVolumenButtonGroup = uibuttongroup(app.GridLayout2);
            app.vistaVolumenButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @vistaVolumenButtonGroupSelectionChanged, true);
            app.vistaVolumenButtonGroup.Visible = 'off';
            app.vistaVolumenAxialButton = uiradiobutton(app.vistaVolumenButtonGroup);
            app.vistaVolumenAxialButton.Tag = 'Axial';
            app.vistaVolumenAxialButton.Text = 'Axial';
            app.vistaVolumenAxialButton.Value = true;
            app.vistaVolumenSagitalButton = uiradiobutton(app.vistaVolumenButtonGroup);
            app.vistaVolumenSagitalButton.Tag = 'Sagital';
            app.vistaVolumenSagitalButton.Text = 'Sagital';
            app.vistaVolumenCoronalButton = uiradiobutton(app.vistaVolumenButtonGroup);
            app.vistaVolumenCoronalButton.Tag = 'Coronal';
            app.vistaVolumenCoronalButton.Text = 'Coronal';
            app.vistaVolumenLabel = uilabel(app.GridLayout2);
            app.vistaVolumenLabel.Visible = 'off';
            app.vistaVolumenLabel.Text = 'Vista volumen';
            app.TiempoSliderLabel = uilabel(app.GridLayout2);
            app.TiempoSliderLabel.Visible = 'off';
            app.TiempoSliderLabel.Text = 'Tiempo';
            app.TiempoSlider = uislider(app.GridLayout2);
            app.TiempoSlider.Limits = [1 500];
            app.TiempoSlider.ValueChangedFcn = createCallbackFcn(app, @TiempoSliderValueChanged, true);
            app.TiempoSlider.Visible = 'off';
            app.TiempoSlider.Value = 1;
            app.TiempoEdit = uieditfield(app.GridLayout2, 'numeric');
            app.TiempoEdit.ValueChangedFcn = createCallbackFcn(app, @TiempoEditValueChanged, true);
            app.TiempoEdit.Visible = 'off';
            app.RestarMediaTemporalVolumenesCheckBox = uicheckbox(app.GridLayout2);
            app.RestarMediaTemporalVolumenesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RestarMediaTemporalVolumenesCheckBoxValueChanged, true);
            app.RestarMediaTemporalVolumenesCheckBox.Visible = 'off';
            app.RestarMediaTemporalVolumenesCheckBox.Text = 'Restar media temporal de volúmenes';
            app.escalaColorButtonGroup = uibuttongroup(app.GridLayout2);
            app.escalaColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @escalaColorButtonGroupSelectionChanged, true);
            app.escalaColorButtonGroup.Title = 'Escala color';
            app.escalaColorButtonGroup.Visible = 'off';
            app.escalaColorLinealButton = uiradiobutton(app.escalaColorButtonGroup);
            app.escalaColorLinealButton.Tag = 'EscalaColorLineal';
            app.escalaColorLinealButton.Text = 'lineal';
            app.escalaColorLinealButton.Value = true;
            app.escalaColorSqrtButton = uiradiobutton(app.escalaColorButtonGroup);
            app.escalaColorSqrtButton.Tag = 'EscalaColorSqrt';
            app.escalaColorSqrtButton.Text = 'sqrt';
            app.scalaColorLogButton = uiradiobutton(app.escalaColorButtonGroup);
            app.scalaColorLogButton.Tag = 'EscalaColorLog';
            app.scalaColorLogButton.Text = 'log';
            app.escalaColorAbsLogButton = uiradiobutton(app.escalaColorButtonGroup);
            app.escalaColorAbsLogButton.Tag = 'EscalaColorAbsLog';
            app.escalaColorAbsLogButton.Text = 'abs(log)';
            app.EtqEvLabel = uilabel(app.GridLayout2);
            app.EtqEvLabel.Visible = 'off';
            app.EtqEvLabel.Text = 'Etq. Ev.';
            app.etiquetasEventosEditField = uieditfield(app.GridLayout2, 'text');
            app.etiquetasEventosEditField.Visible = 'off';
            app.etiquetasEventosEditField.Value = '{''BASAL'',''NINOSAGUA'',''METENAGUA'',''JUEGANAGUA'',''FLOTAAGUA'',''ALGOTIBURON'',''ALGOSANGRE'',''GRITOSPLAYA'',''SALENNINOS'',''MADREBUSCA''}';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DYNOT4D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end